classdef APP_TESTEOBLANCO_NO_USAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_de_Prueba              matlab.ui.Figure
        ListBoxLabel               matlab.ui.control.Label
        CanalesdisponiblesListBox  matlab.ui.control.ListBox
        ListBox2Label              matlab.ui.control.Label
        CanalesaprocesarListBox    matlab.ui.control.ListBox
        MasButton                  matlab.ui.control.StateButton
        MenosButton                matlab.ui.control.StateButton
        ProcesarButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        canales_listado;
        numero_del_canal = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.canales_listado = app.CanalesdisponiblesListBox.Items;        
        end

        % Value changed function: MasButton
        function MasButtonValueChanged(app, event)
            array_chdisponible = app.CanalesdisponiblesListBox.Items;%Disponibles
            array_chselecionado = app.CanalesdisponiblesListBox.Value;%Selección
            array_chanalizar = app.CanalesaprocesarListBox.Items;%A procesar
            cnt = 1;
            %Borrar de list1 los elegidos
            indices_elegidos = ismember(array_chdisponible,array_chselecionado);%Indices a revisar
            nueva_disponible = cell(1,length(array_chdisponible)-length(array_chselecionado));
            for s=1:length(indices_elegidos)
                if indices_elegidos(s) == 0
                    nueva_disponible(cnt) = array_chdisponible(s);
                    cnt = cnt+1;
                end
            end
            %Agregar en orden en la lista de seleccionados
            nuevo_array_chanalizar = [array_chselecionado,array_chanalizar];
            indices_sel = ismember(app.canales_listado,nuevo_array_chanalizar);%Indices a revisar
            nueva_seleccion = cell(1,length(nuevo_array_chanalizar));
            cnt = 1;
            for s=1:length(indices_sel)
                if indices_sel(s) == 1
                    nueva_seleccion(cnt) = app.canales_listado(s);
                    cnt = cnt+1;
                end
            end
            app.CanalesdisponiblesListBox.Items = nueva_disponible;
            app.CanalesaprocesarListBox.Items = nueva_seleccion;
        end

        % Value changed function: MenosButton
        function MenosButtonValueChanged(app, event)
            array_chdisponible = app.CanalesdisponiblesListBox.Items;%Disponibles
            array_chselecionado = app.CanalesaprocesarListBox.Value;%Selección
            array_chanalizar = app.CanalesaprocesarListBox.Items;%A procesar
            cnt = 1;
            %Borrar de list5 los elegidos
            indices_elegidos = ismember(array_chanalizar,array_chselecionado);%Indices a revisar
            nueva_seleccion = cell(1,length(array_chanalizar)-length(array_chselecionado));
            for s=1:length(indices_elegidos)
                if indices_elegidos(s) == 0
                    nueva_seleccion(cnt) = array_chanalizar(s);
                    cnt = cnt+1;
                end
            end
            %Agregar en orden en la lista de seleccionados
            nuevo_array_disponible = [array_chselecionado,array_chdisponible];
            indices_sel = ismember(app.canales_listado,nuevo_array_disponible);%Indices a revisar
            nueva_disponible = cell(1,length(nuevo_array_disponible));
            cnt = 1;
            for s=1:length(indices_sel)
                if indices_sel(s) == 1
                    nueva_disponible(cnt) = app.canales_listado(s);
                    cnt = cnt+1;
                end
            end
            app.CanalesdisponiblesListBox.Items = nueva_disponible;
            app.CanalesaprocesarListBox.Items = nueva_seleccion;
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            array_chanalizar = app.CanalesaprocesarListBox.Items;%A procesar
            indices_chanalizar = ismember(app.canales_listado,array_chanalizar);
            array_numero_ch = zeros(1,length(array_chanalizar));
            cont = 1;
            for n=1:length(indices_chanalizar)
                if indices_chanalizar(n)==1
                    array_numero_ch(cont) = n;
                    cont = cont+1;
                end
            end
            app.numero_del_canal = array_numero_ch;
            out = array_numero_ch
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_de_Prueba = uifigure('Visible', 'off');
            app.App_de_Prueba.Name = 'App de prueba';
            app.ListBoxLabel = uilabel(app.App_de_Prueba);
            app.ListBoxLabel.Text = 'List Box';
            app.CanalesdisponiblesListBox = uilistbox(app.App_de_Prueba);
            app.CanalesdisponiblesListBox.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N'};
            app.CanalesdisponiblesListBox.Multiselect = 'on';
            app.CanalesdisponiblesListBox.Value = {'A'};
            app.ListBox2Label = uilabel(app.App_de_Prueba);
            app.ListBox2Label.Text = 'List Box2';
            app.CanalesaprocesarListBox = uilistbox(app.App_de_Prueba);
            app.CanalesaprocesarListBox.Items = {};
            app.CanalesaprocesarListBox.Value = {};
            app.MasButton = uibutton(app.App_de_Prueba, 'state');
            app.MasButton.ValueChangedFcn = createCallbackFcn(app, @MasButtonValueChanged, true);
            app.MasButton.Text = 'Mas';
            app.MenosButton = uibutton(app.App_de_Prueba, 'state');
            app.MenosButton.ValueChangedFcn = createCallbackFcn(app, @MenosButtonValueChanged, true);
            app.MenosButton.Text = 'Menos';
            app.ProcesarButton = uibutton(app.App_de_Prueba, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            % Show the figure after all components are created
            app.App_de_Prueba.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_TESTEOBLANCO_NO_USAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_de_Prueba)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_de_Prueba)
        end
    end
end