classdef generacionalgoritmoECG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EntrenamientodelclasificadorTab  matlab.ui.container.Tab
        ExactituddelclasificadorLabel  matlab.ui.control.Label
        SegmentosPrueba                matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdepruebaEditFieldLabel  matlab.ui.control.Label
        SegmentosValidacion            matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdevalidacinEditFieldLabel  matlab.ui.control.Label
        CapasOcultasDropDown           matlab.ui.control.DropDown
        CapasOcultasDropDownLabel      matlab.ui.control.Label
        AlgoritmoparaelentrenamientoDropDown  matlab.ui.control.DropDown
        AlgoritmoparaelentrenamientoDropDownLabel  matlab.ui.control.Label
        SegmentosEntrenamiento         matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdeentrenamientoLabel  matlab.ui.control.Label
        GuardarclasificadorButton      matlab.ui.control.Button
        EntrenarelclasificadorespecificadoButton  matlab.ui.control.Button
        PARMETROSPARAUNCLASIFICADORRNALabel  matlab.ui.control.Label
        TextoPorcentaje3               matlab.ui.control.Label
        TextoPorcentaje2               matlab.ui.control.Label
        TextoPorcentaje                matlab.ui.control.Label
        CargarMATCaractersticasECGButton  matlab.ui.control.StateButton
        SELECCINYENTRENAMIENTODELCLASIFICADORLabel  matlab.ui.control.Label
        EPILEPTICECGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        SeleccionarclasificadorButtonGroup  matlab.ui.container.ButtonGroup
        JerrquicoButton                matlab.ui.control.ToggleButton
        KmeansButton                   matlab.ui.control.ToggleButton
        RedNeuronalButton              matlab.ui.control.ToggleButton
    end


    properties (Access = public)
        CallingApp
        %Variables compartidas entre ventanas  
        s_ventana %Ventana de tiempo
        canales %Rango de canales para la extracción, en esta versión SÓLO se puede 1 canal
        
        %Canal a emplear y muestras por ventana Ictal
        cIctal
        muestrasIctal
        %Canal a emplear y muestras por ventana Sano
        cSano
        muestrasSano
        %Canal a emplear y muestras por ventana Preictal
        cPreictal
        muestrasPreictal
        %Canal a emplear y muestras por ventana Interictal
        cInterictal
        muestrasInterictal
        
        op %Vector de opciones
        Matriz_featuresIctal
        channel_ventanaIctal
        Matriz_featuresSano
        channel_ventanaSano
        Matriz_featuresPreictal
        channel_ventanaPreictal
        Matriz_featuresInterictal
        channel_ventanaInterictal
        
        %Wavelet madre y niveles de descomposición
        madre
        n
        
        %Structs para emplear sin cargar (?
        
        %Struct recuperado al cargar el .MAT
        Estructura_Caracteristicas
        
        %Algoritmo para guardar en el struct del clasificador         
        AlgorimoAprendizajeA
        
        Matriz_features_wavelet
        coeficientes_wavelet
        label
        featureop
        labelwave
        Wavelet_data
        archivo
        
        %Vector de nombres de los canales
        Nombres_CanalesIctal
        Nombres_CanalesSano
        Nombres_CanalesPreictal
        Nombres_CanalesInterictal
        
        %Variables para la carga de archivo EEG Ictal
        datosIctal
        headerIctal
        FsIctal
        
        %Variables para la carga de archivo EEG Sano
        datosSano
        headerSano
        FsSano        
        %Variables para la carga de archivo EEG Preictal
        datosPreictal
        headerPreictal
        FsPreictal
        %Variables para la carga de archivo EEG Interictal
        datosInterictal
        headerInterictal
        FsInterictal
        
        %vector_caracteristicasECG
        
        %Tipo de clasificación (2, 3 o 4 clases)
        ClassType = 4;
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app, mainapp)
            app.CallingApp = mainapp; 
        end

        % Selection changed function: 
        % SeleccionarclasificadorButtonGroup
        function SeleccionarclasificadorButtonGroupSelectionChanged(app, event)
            if (app.RedNeuronalButton.Value == 1)
                app.SegmentosEntrenamiento.Enable = 'on';
                app.SegmentosValidacion.Enable = 'on';
                app.SegmentosPrueba.Enable = 'on';
                app.CapasOcultasDropDown.Enable = 'on';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'on';
            else
                app.SegmentosEntrenamiento.Enable = 'off';
                app.SegmentosValidacion.Enable = 'off';
                app.SegmentosPrueba.Enable = 'off';
                app.CapasOcultasDropDown.Enable = 'off';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'off';            
            end
            
        end

        % Value changed function: CargarMATCaractersticasECGButton
        function CargarMATCaractersticasECGButtonValueChanged(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            %app.vector_caracteristicasECG = load('VecCarSD1SD2Pos.mat');            
        end

        % Button pushed function: 
        % EntrenarelclasificadorespecificadoButton
        function EntrenarelclasificadorespecificadoButtonPushed(app, event)
            %Se crea y entrena el clasificador especificado
            
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                
                vector_etiquetas_ecg = load('EtiquetasECGRedesNeuronales.mat');                
                vector_caracteristicasECG = load('VecCarSD1SD2Pos.mat'); 
                %vector_etiquetas_ecg = [cargar_etiquetas_ecg];
                
                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
                net = patternnet(CapasOcultas, Func_entrenamiento);
                
                %Se entrena la red neuronal
                [net,tr] = train(net,vector_caracteristicasECG.VecCarSD1SD2Pos',vector_etiquetas_ecg.EtiquetasRedesNeuronales');
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;
                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 10;
                    divival = 20;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                etiquetas_salida = net(vector_caracteristicasECG.VecCarSD1SD2Pos');
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_ecg.EtiquetasRedesNeuronales',etiquetas_salida);

                Reposo_11 = cm(1,1);%Ictal correcto
                Reposo_12 = cm(1,2);%Ictal falso (sano)

                Ejercicio_21 = cm(2,1);%Sano falso (ictal)
                Ejercicio_22 = cm(2,2);%Sano correcto

                accu = num2str(100*((Reposo_11+Ejercicio_22) / (Reposo_11+Reposo_12+...
                    Ejercicio_21+Ejercicio_22)));  %Exactitud
                plotconfusion(vector_etiquetas_ecg.EtiquetasRedesNeuronales',etiquetas_salida,{'Matriz de Confusión'});
                set(gca,'xticklabel',{'Reposo' 'Ejercicio' 'Exactitud'})
                set(gca,'yticklabel',{'Reposo' 'Ejercicio' ''})
                app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");

            end
        end

        % Button pushed function: GuardarclasificadorButton
        function GuardarclasificadorButtonPushed(app, event)
           
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Clasificador.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            %fullFileName = fullfile(path, file);            
            %save(fullFileName,'net');
            save('ClasificadorPRUEBA.mat','app.AlgorimoAprendizajeA');
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.ClasificadorAnotacionesUIFigure,message,'Aviso',...
            'Icon','success'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientodelclasificadorTab = uitab(app.TabGroup);
            app.EntrenamientodelclasificadorTab.Title = 'Entrenamiento del clasificador';
            app.SeleccionarclasificadorButtonGroup = uibuttongroup(app.EntrenamientodelclasificadorTab);
            app.SeleccionarclasificadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccionarclasificadorButtonGroupSelectionChanged, true);
            app.SeleccionarclasificadorButtonGroup.Title = 'Seleccionar clasificador';
            app.RedNeuronalButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.RedNeuronalButton.Text = 'Red Neuronal';
            app.RedNeuronalButton.Value = true;
            app.KmeansButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.KmeansButton.Text = 'K-means';
            app.JerrquicoButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.JerrquicoButton.Text = 'Jerárquico';
            app.EPILEPTICECGANALYSISTOOLBOXLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.EPILEPTICECGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC ECG ANALYSIS TOOLBOX';
            app.SELECCINYENTRENAMIENTODELCLASIFICADORLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.SELECCINYENTRENAMIENTODELCLASIFICADORLabel.Text = 'SELECCIÓN Y ENTRENAMIENTO DEL CLASIFICADOR';
            app.CargarMATCaractersticasECGButton = uibutton(app.EntrenamientodelclasificadorTab, 'state');
            app.CargarMATCaractersticasECGButton.ValueChangedFcn = createCallbackFcn(app, @CargarMATCaractersticasECGButtonValueChanged, true);
            app.CargarMATCaractersticasECGButton.Text = 'Cargar MAT Características ECG';
            app.TextoPorcentaje = uilabel(app.EntrenamientodelclasificadorTab);
            app.TextoPorcentaje.Text = '%';
            app.TextoPorcentaje2 = uilabel(app.EntrenamientodelclasificadorTab);
            app.TextoPorcentaje2.Text = '%';
            app.TextoPorcentaje3 = uilabel(app.EntrenamientodelclasificadorTab);
            app.TextoPorcentaje3.Text = '%';
            app.PARMETROSPARAUNCLASIFICADORRNALabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.PARMETROSPARAUNCLASIFICADORRNALabel.Text = 'PARÁMETROS PARA UN CLASIFICADOR RNA';
            app.EntrenarelclasificadorespecificadoButton = uibutton(app.EntrenamientodelclasificadorTab, 'push');
            app.EntrenarelclasificadorespecificadoButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarelclasificadorespecificadoButtonPushed, true);
            app.EntrenarelclasificadorespecificadoButton.Text = 'Entrenar el clasificador especificado';
            app.GuardarclasificadorButton = uibutton(app.EntrenamientodelclasificadorTab, 'push');
            app.GuardarclasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarclasificadorButtonPushed, true);
            app.GuardarclasificadorButton.Text = 'Guardar clasificador';
            app.SegmentosdelasealparaelsetdeentrenamientoLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.SegmentosdelasealparaelsetdeentrenamientoLabel.Text = {'Segmentos de la señal para'; 'el set de entrenamiento'};
            app.SegmentosEntrenamiento = uieditfield(app.EntrenamientodelclasificadorTab, 'numeric');
            app.SegmentosEntrenamiento.Limits = [0 90];
            app.SegmentosEntrenamiento.Value = 70;
            app.AlgoritmoparaelentrenamientoDropDownLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.AlgoritmoparaelentrenamientoDropDownLabel.Text = {'Algoritmo para el'; 'entrenamiento'};
            app.AlgoritmoparaelentrenamientoDropDown = uidropdown(app.EntrenamientodelclasificadorTab);
            app.AlgoritmoparaelentrenamientoDropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd', '', '', ''};
            app.AlgoritmoparaelentrenamientoDropDown.Value = 'trainscg';
            app.CapasOcultasDropDownLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.CapasOcultasDropDownLabel.Text = {'Capas '; 'Ocultas'};
            app.CapasOcultasDropDown = uidropdown(app.EntrenamientodelclasificadorTab);
            app.CapasOcultasDropDown.Items = {'8', '10', '12', '15', '', '', ''};
            app.CapasOcultasDropDown.Value = '8';
            app.SegmentosdelasealparaelsetdevalidacinEditFieldLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.SegmentosdelasealparaelsetdevalidacinEditFieldLabel.Text = {'Segmentos de la señal para'; 'el set de validación'};
            app.SegmentosValidacion = uieditfield(app.EntrenamientodelclasificadorTab, 'numeric');
            app.SegmentosValidacion.Limits = [0 90];
            app.SegmentosValidacion.Value = 15;
            app.SegmentosdelasealparaelsetdepruebaEditFieldLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.SegmentosdelasealparaelsetdepruebaEditFieldLabel.Text = {'Segmentos de la señal para'; 'el set de prueba'};
            app.SegmentosPrueba = uieditfield(app.EntrenamientodelclasificadorTab, 'numeric');
            app.SegmentosPrueba.Limits = [0 90];
            app.SegmentosPrueba.Value = 15;
            app.ExactituddelclasificadorLabel = uilabel(app.EntrenamientodelclasificadorTab);
            app.ExactituddelclasificadorLabel.Text = 'Exactitud del clasificador: -%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generacionalgoritmoECG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn2(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end