classdef loginanotaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoginAnotacionesUIFigure     matlab.ui.Figure
        Button                       matlab.ui.control.Button
        CrearalgoritmoECGButton      matlab.ui.control.StateButton
        ANOTACIONESAUTOMTICASLabel   matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        AnotacionesAutomticasButton  matlab.ui.control.Button
        CrearalgoritmoEEGButton      matlab.ui.control.StateButton
        ContraseaEditField           matlab.ui.control.EditField
        ContraseaEditFieldLabel      matlab.ui.control.Label
        UsuarioEditField             matlab.ui.control.EditField
        UsuarioEditFieldLabel        matlab.ui.control.Label
        IniciarSesinButton           matlab.ui.control.StateButton
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp
        usuario = 0
        pass = 0
        MainAlgoapp
        MainGrafiapp
        MainAlgoECGapp
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        MLTesteo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            %app.Label.Text = num2str(app.CallingApp.MLTesteo);
            app.AnotacionesAutomticasButton.Enable = 'off';
            app.CrearalgoritmoEEGButton.Enable = 'off';
            app.CrearalgoritmoECGButton.Enable = 'off';
        end

        % Value changed function: IniciarSesinButton
        function IniciarSesinButtonValueChanged(app, event)
      if ((app.UsuarioEditField.Value == "Doctor")&&(app.ContraseaEditField.Value == "1234"))
          app.usuario = 1;
          app.AnotacionesAutomticasButton.Enable = 'on';
          app.CrearalgoritmoEEGButton.Enable = 'off';
          app.CrearalgoritmoECGButton.Enable = 'off';
      end  
      if ((app.UsuarioEditField.Value == "Admin")&&(app.ContraseaEditField.Value == "1234"))
          app.usuario = 1;
          app.AnotacionesAutomticasButton.Enable = 'on';
          app.CrearalgoritmoEEGButton.Enable = 'on';
          app.CrearalgoritmoECGButton.Enable = 'on';
      end  
        end

        % Value changed function: CrearalgoritmoEEGButton
        function CrearalgoritmoEEGButtonValueChanged(app, event)
            app.MainAlgoapp = generacionalgoritmo(app);
        end

        % Button pushed function: AnotacionesAutomticasButton
        function AnotacionesAutomticasButtonPushed(app, event)
            app.MainGrafiapp = inicioanotacionesauto(app);
        end

        % Value changed function: CrearalgoritmoECGButton
        function CrearalgoritmoECGButtonValueChanged(app, event)
            app.MainAlgoECGapp = generacionalgoritmoECG(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Interfaz_Inicio;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoginAnotacionesUIFigure = uifigure('Visible', 'off');
            app.LoginAnotacionesUIFigure.Name = 'Login Anotaciones';
            app.Image = uiimage(app.LoginAnotacionesUIFigure);
            app.Image.ImageSource = 'logouvg.jpg';
            app.IniciarSesinButton = uibutton(app.LoginAnotacionesUIFigure, 'state');
            app.IniciarSesinButton.ValueChangedFcn = createCallbackFcn(app, @IniciarSesinButtonValueChanged, true);
            app.IniciarSesinButton.Text = 'Iniciar Sesión';
            app.UsuarioEditFieldLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.LoginAnotacionesUIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.LoginAnotacionesUIFigure, 'text');
            app.CrearalgoritmoEEGButton = uibutton(app.LoginAnotacionesUIFigure, 'state');
            app.CrearalgoritmoEEGButton.ValueChangedFcn = createCallbackFcn(app, @CrearalgoritmoEEGButtonValueChanged, true);
            app.CrearalgoritmoEEGButton.Text = 'Crear algoritmo EEG';
            app.AnotacionesAutomticasButton = uibutton(app.LoginAnotacionesUIFigure, 'push');
            app.AnotacionesAutomticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesAutomticasButtonPushed, true);
            app.AnotacionesAutomticasButton.Text = 'Anotaciones Automáticas';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.ANOTACIONESAUTOMTICASLabel = uilabel(app.LoginAnotacionesUIFigure);
            app.ANOTACIONESAUTOMTICASLabel.Text = 'ANOTACIONES AUTOMÁTICAS';
            app.CrearalgoritmoECGButton = uibutton(app.LoginAnotacionesUIFigure, 'state');
            app.CrearalgoritmoECGButton.ValueChangedFcn = createCallbackFcn(app, @CrearalgoritmoECGButtonValueChanged, true);
            app.CrearalgoritmoECGButton.Text = 'Crear algoritmo ECG';
            app.Button = uibutton(app.LoginAnotacionesUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'goback.jpg';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.LoginAnotacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loginanotaciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoginAnotacionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoginAnotacionesUIFigure)
        end
    end
end