classdef entrenamientos4clases < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        EntrenamientoRNSVMTab          matlab.ui.container.Tab
        ExactituddelclasificadorLabel  matlab.ui.control.Label
        GuardarButton                  matlab.ui.control.Button
        EntrenarButton                 matlab.ui.control.Button
        MquinadeSoporteVectorialSVMPanel  matlab.ui.container.Panel
        KernelaemplearDropDown         matlab.ui.control.DropDown
        KernelDropDownLabel            matlab.ui.control.Label
        NmerodeParticionesKSpinner     matlab.ui.control.Spinner
        KfoldsLabel                    matlab.ui.control.Label
        ValidacinCruzadaLabel          matlab.ui.control.Label
        ClasificadorRedNeuronalPanel   matlab.ui.container.Panel
        ManualCheckBox                 matlab.ui.control.CheckBox
        CapasOcultasDropDown           matlab.ui.control.DropDown
        CapasOcultasDropDownLabel      matlab.ui.control.Label
        LearningRateEditField          matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel     matlab.ui.control.Label
        EpochsEditField                matlab.ui.control.NumericEditField
        EpochsEditFieldLabel           matlab.ui.control.Label
        AlgoritmoparaelentrenamientoDropDown  matlab.ui.control.DropDown
        AlgoritmoRNADropDownLabel      matlab.ui.control.Label
        PruebaEditFieldLabel           matlab.ui.control.Label
        SegmentosPrueba                matlab.ui.control.NumericEditField
        ValidacionEditFieldLabel       matlab.ui.control.Label
        SegmentosValidacion            matlab.ui.control.NumericEditField
        SegmentosEntrenamiento         matlab.ui.control.NumericEditField
        EntrenamientoEditFieldLabel    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SeleccionarclasificadorButtonGroup  matlab.ui.container.ButtonGroup
        SVMButton                      matlab.ui.control.ToggleButton
        RedNeuronalButton              matlab.ui.control.ToggleButton
        CargarmatButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        InterfazdecontrolrobticoTab    matlab.ui.container.Tab
        TrayectoriaButtonGroup         matlab.ui.container.ButtonGroup
        PickandPlaceButton             matlab.ui.control.RadioButton
        NormalButton                   matlab.ui.control.RadioButton
        ControlManualPanel             matlab.ui.container.Panel
        MetodoDropDown                 matlab.ui.control.DropDown
        MetodoDropDownLabel            matlab.ui.control.Label
        Clase4Gesto                    matlab.ui.control.Button
        Clase3Gesto                    matlab.ui.control.Button
        Clase2Gesto                    matlab.ui.control.Button
        Clase1Gesto                    matlab.ui.control.Button
        ActividadPanel                 matlab.ui.container.Panel
        gestonuevofig                  matlab.ui.control.UIAxes
        HistricoP560Panel              matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        ControlSerialPanel             matlab.ui.container.Panel
        TmuestraEditField              matlab.ui.control.NumericEditField
        TmuestraEditFieldLabel         matlab.ui.control.Label
        DesconectarButton              matlab.ui.control.Button
        ConectarButton                 matlab.ui.control.Button
        PuertoCOMDropDown              matlab.ui.control.DropDown
        PuertoCOMDropDownLabel         matlab.ui.control.Label
        BaudRateEditField              matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel         matlab.ui.control.Label
        TipodecontrolButtonGroup       matlab.ui.container.ButtonGroup
        ControlmanualButton            matlab.ui.control.RadioButton
        ControlautomticoButton         matlab.ui.control.RadioButton
        CargarEntrenamientoButton      matlab.ui.control.Button
        ClaseEditField                 matlab.ui.control.EditField
        ClaseEditFieldLabel            matlab.ui.control.Label
        ControlOfflinePanel            matlab.ui.container.Panel
        SimularButton                  matlab.ui.control.Button
        CargarsegmentoButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        %% propiedades
        Clasificador_Entrenado
        Estructura_Caracteristicas
        vector_config
        AlgorimoAprendizajeA;
        Fs_gesto_nuevo
        datosgesto_nuevo
        clase_prueba
        qtest
        loadedNet
        continueLoop
        s
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas  
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);           
                espacio_trabajo_temp = load(strcat(path,file),'Features_result');
                app.Estructura_Caracteristicas = espacio_trabajo_temp.Features_result;
                app.vector_config = espacio_trabajo_temp.Features_result.Vector_Caracteristicas;
            end
        end

        % Selection changed function: 
        % SeleccionarclasificadorButtonGroup
        function SeleccionarclasificadorButtonGroupSelectionChanged(app, event)
             if (app.RedNeuronalButton.Value == 1)
                app.SegmentosEntrenamiento.Enable = 'on';
                app.SegmentosValidacion.Enable = 'on';
                app.SegmentosPrueba.Enable = 'on';
                app.CapasOcultasDropDown.Enable = 'on';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'on';
                app.LearningRateEditField.Enable ='on';
                app.EpochsEditField.Enable = 'on';
                app.NmerodeParticionesKSpinner.Enable = 'off';
                app.KernelaemplearDropDown.Enable = 'off';

            else
                app.SegmentosEntrenamiento.Enable = 'off';
                app.LearningRateEditField.Enable ='off';
                app.EpochsEditField.Enable = 'off';
                app.SegmentosValidacion.Enable = 'off';
                app.SegmentosPrueba.Enable = 'off';
                app.CapasOcultasDropDown.Enable = 'off';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'off';

                app.NmerodeParticionesKSpinner.Enable = 'on';
                app.KernelaemplearDropDown.Enable = 'on';
            end
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
    %Se verifica si se desean 2, 3 o 4 clases y el tipo de análisis
            %empleado para extraer las características
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
                        
            %Se cargan los vectores de caracteristicas correspondientes
            caracteristicas_gesto_1 = app.Estructura_Caracteristicas.Car_gesto_1.VectorCaracteristicas_gesto_1;            
            caracteristicas_gesto_2 =  app.Estructura_Caracteristicas.Car_gesto_2.VectorCaracteristicas_gesto_2;
            
            %Homogeneización de las señales: Ya que puede que las señales
            %tengan distinta duración, puede que tengan una cantidad de
            %ventanas distintas entre ellas. Se acotará a que todas tengan
            %la misma cantidad que la que tenga menor número de ventanas
            cantidad_ventanas = [length(caracteristicas_gesto_1),length(caracteristicas_gesto_2)];
            if (Tipo_class == 3)
                caracteristicas_gesto_3 = app.Estructura_Caracteristicas.Car_gesto_3.VectorCaracteristicas_gesto_3;    
                cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_gesto_3)];
            elseif (Tipo_class == 4)
                caracteristicas_gesto_3 = app.Estructura_Caracteristicas.Car_gesto_3.VectorCaracteristicas_gesto_3;
                caracteristicas_gesto_4 = app.Estructura_Caracteristicas.Car_gesto_4.VectorCaracteristicas_gesto_4;
                cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_gesto_3),length(caracteristicas_gesto_4)];
            end                       
            
            %Se encuentra cuál es la menor cantidad de ventanas entre los
            %vectores involucrados en el análisis, este es el máximo de
            %ventanas que pueden haber. Se acotan los vectores de car.
            cantidad_ventanasmaximas = min(cantidad_ventanas);
            
            if (Tipo_class ==2)
                caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
                %Unimos los vectores en una matriz con todas las
                %características
                matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2'];
            elseif (Tipo_class == 3)
                caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_3 = caracteristicas_gesto_3(1:cantidad_ventanasmaximas,:);
                matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
                                                  caracteristicas_gesto_3'];
            elseif (Tipo_class == 4)
                caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_3 = caracteristicas_gesto_3(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_4 = caracteristicas_gesto_4(1:cantidad_ventanasmaximas,:);
                matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
                                                  caracteristicas_gesto_3',caracteristicas_gesto_4'];
            end            
            
            %Se crea y entrena el clasificador especificado
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                if (Tipo_class == 2)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                elseif (Tipo_class == 3)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                elseif (Tipo_class == 4)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,3*cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
                                           zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,3*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                end
                    
                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
                net = patternnet(CapasOcultas, Func_entrenamiento);
                
                if app.ManualCheckBox.Value == 1    
                    % Set the number of epochs based on the stored value
                    app.EpochsEditField.Enable = 1;
                    app.LearningRateEditField.Enable = 1;
                    net.trainParam.epochs = app.EpochsEditField.Value;
                    net.trainParam.lr = app.LearningRateEditField.Value;
                else 
                    app.EpochsEditField.Enable = 0;
                    app.LearningRateEditField.Enable = 0;
                end
 
                %Se entrena la red neuronal
                [net,tr] = train(net,matriz_completa_caracteristicas,vector_etiquetas_eeg);
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;
                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 20;
                    divival = 10;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                etiquetas_salida = net(matriz_completa_caracteristicas); 
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_eeg,etiquetas_salida);
                if (Tipo_class == 2)
                    gesto_1_11 = cm(1,1);%_gesto_1 correcto
                    gesto_1_12 = cm(1,2);%_gesto_1 falso (sano)                   
                    
                    gesto_2_21 = cm(2,1);%_gesto_2 falso (ictal)
                    gesto_2_22 = cm(2,2);%_gesto_2 correcto
                    
                    accu = num2str(100*((gesto_1_11+gesto_2_22) / (gesto_1_11+gesto_1_12+...
                                        gesto_2_21+gesto_2_22)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' ''})                                        
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 3)
                    gesto_1_11 = cm(1,1);%_gesto_1 correcto
                    gesto_1_12 = cm(1,2);%_gesto_1 falso (sano)
                    gesto_1_13 = cm(1,3); %_gesto_1 falso (preict)                    
                    
                    gesto_2_21 = cm(2,1);%_gesto_2 falso (ictal)
                    gesto_2_22 = cm(2,2);%_gesto_2 correcto
                    gesto_2_23 = cm(2,3);%_gesto_2 falso (preict)
                    
                    gesto_3_31 = cm(3,1);%_gesto_3 falso (ictal)
                    gesto_3_32 = cm(3,2);%_gesto_3 falso (sano)
                    gesto_3_33 = cm(3,3);%_gesto_3 correcto
                    
                    accu = num2str(100*((gesto_1_11+gesto_2_22+gesto_3_33) / (gesto_1_11+gesto_1_12+gesto_1_13+...
                                        gesto_2_21+gesto_2_22+gesto_2_23+...    
                                        gesto_3_31+gesto_3_32+gesto_3_33)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' 'Clase 3' ''})
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 4)
                    gesto_1_11 = cm(1,1);%_gesto_1 correcto
                    gesto_1_12 = cm(1,2);%_gesto_1 falso (sano)
                    gesto_1_13 = cm(1,3); %_gesto_1 falso (preict)
                    gesto_1_14 = cm(1,4);%_gesto_1 falso (inter)
                    
                    gesto_2_21 = cm(2,1);%_gesto_2 falso (ictal)
                    gesto_2_22 = cm(2,2);%_gesto_2 correcto
                    gesto_2_23 = cm(2,3);%_gesto_2 falso (preict)
                    gesto_2_24 = cm(2,4);%_gesto_2 falso (inter)
                    
                    gesto_3_31 = cm(3,1);%_gesto_3 falso (ictal)
                    gesto_3_32 = cm(3,2);%_gesto_3 falso (sano)
                    gesto_3_33 = cm(3,3);%_gesto_3 correcto
                    gesto_3_34 = cm(3,4);%_gesto_3 falso (inter)
                    
                    gesto_4_41 = cm(4,1);%_gesto_4 falso (ictal)
                    gesto_4_42 = cm(4,2);%_gesto_4 falso (sano)
                    gesto_4_43 = cm(4,3);%_gesto_4 falso (preict)
                    gesto_4_44 = cm(4,4);%_gesto_4 correcto
                    
                    accu = num2str(100*((gesto_1_11+gesto_2_22+gesto_3_33+gesto_4_44) / (gesto_1_11+gesto_1_12+gesto_1_13+gesto_1_14+...
                                        gesto_2_21+gesto_2_22+gesto_2_23+gesto_2_24+...    
                                        gesto_3_31+gesto_3_32+gesto_3_33+gesto_3_34+...
                                        gesto_4_41+gesto_4_42+gesto_4_43+gesto_4_44)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Clase 4' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Clase 4' ''})
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",accu,"%");
                end    


            elseif (app.SVMButton.Value == 1) %SVM
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:)];
                elseif (Tipo_class == 3)
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_3.Etiquetas_gesto_3(1:cantidad_ventanasmaximas,:)];
                elseif (Tipo_class == 4)                   
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_3.Etiquetas_gesto_3(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_4.Etiquetas_gesto_4(1:cantidad_ventanasmaximas,:)];
                end
                
                
                %Se transpone la matriz de características para este
                %clasificador (SVM)
                matriz_completa_caracteristicas = matriz_completa_caracteristicas';
                
                %Validacion cruzada para el entrenamiento del clasificador
                k = app.NmerodeParticionesKSpinner.Value;
                cvFolds = crossvalind('Kfold', vector_etiquetas_eeg, k);   
                cp = classperf(vector_etiquetas_eeg);        %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx;
                    %Obtener conjuntos train y test
                    datos_entrenamiento = matriz_completa_caracteristicas(trainIdx,:);
                    etiquetas_entrenamiento = vector_etiquetas_eeg(trainIdx,:);
                    datos_prueba = matriz_completa_caracteristicas(testIdx,:);
                    etiquetas_prueba = vector_etiquetas_eeg(testIdx,:);                  
                end
                
                %Parámetros y entrenamiento de la SVM
                kernel = app.KernelaemplearDropDown.Value;
                t_SVM = templateSVM('Standardize',true,'KernelFunction',kernel);
                model_svm = fitcecoc(datos_entrenamiento,etiquetas_entrenamiento,'Learners',t_SVM,'FitPosterior',true,...
                    'Verbose',2);
                [labelPred,~] =  predict(model_svm, datos_prueba);
                etiquetas_generadas = labelPred;                
                Prueba_eti = categorical(etiquetas_prueba');
                Predict_eti = categorical(etiquetas_generadas');
                app.AlgorimoAprendizajeA = model_svm;
                %Graficamos la matriz de confusión
                plotconfusion(Prueba_eti,Predict_eti,{'Matriz de Confusión'});
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' ''})
                    %Calculamos la exactitud del clasificador
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 3)
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' 'Clase 3' ''})
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3))/(s(1)+s(2)+s(3));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 4)                   
                    set(gca,'xticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Clase 4' 'Exactitud'})
                    set(gca,'yticklabel',{'Clase 1' 'Clase 2' 'Clase 3' 'Clase 4' ''})
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3)+matriz(4,4))/(s(1)+s(2)+s(3)+s(4));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                end
            end
        end

        % Button pushed function: CargarsegmentoButton
        function CargarsegmentoButtonPushed(app, event)
            Features_result_nuevo = struct();

            %Seleccionar archivo obtenido de base de datos
            [file,path,~] = uigetfile({'*.mat'}, 'Selecciona la señal nueva');
            %Si no se hace seleccion
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'gesto_nuevo'
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal EMG a la variable de datos respectiva
                app.datosgesto_nuevo = workspace.gesto.data;
                app.Fs_gesto_nuevo = workspace.gesto.sampling_frequency;          
            end
           
            
            if app.Clasificador_Entrenado.signaltype == 1 && app.Clasificador_Entrenado.Vector_op(end) == 1
               % ESTE SI REQUIERE DE LA Fs, FRECUENCIA
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV3023(app.datosgesto_nuevo,app.Fs_gesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.Clasificador_Entrenado.Vector_op);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Features_result_nuevo.Matriz_features_nuevo = vector_gesto_nuevo;
               Features_result_nuevo.channel_ventana_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.Clasificador_Entrenado.Vector_op;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
            else
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV2023(app.datosgesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.Clasificador_Entrenado.Vector_op);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Features_result_nuevo.Matriz_features_nuevo = vector_gesto_nuevo;
               Features_result_nuevo.channel_ventana_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.Clasificador_Entrenado.Vector_op;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
            end
           
            x_prueba = Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo;
            net = app.Clasificador_Entrenado.Clasificador;
            
           if app.Clasificador_Entrenado.Tipo_Clasificador == 0    
               % Assuming your loaded network is stored in the variable 'net'
               inputSize = net.inputs{1}.size;
               numFeatures = inputSize;  % Replace with the correct value
               x_prueba = reshape(x_prueba, [numFeatures, 1]);
               y_prueba = net(x_prueba);
               app.clase_prueba = vec2ind(y_prueba);
               app.ClaseEditField.Value = num2str(app.clase_prueba);

           else
               app.clase_prueba = predict(net, x_prueba)
               app.ClaseEditField.Value = num2str(app.clase_prueba);
           end


           ylim(app.gestonuevofig, [min(app.datosgesto_nuevo) max(app.datosgesto_nuevo)]);
            Epocas = size(app.datosgesto_nuevo,2) ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, (Epocas - 1) / app.Fs_gesto_nuevo, Epocas);
            h1 = plot(app.gestonuevofig, t, app.datosgesto_nuevo, 'Color','r') %se plotea esa muestra
           
         
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            Clasificador_Entrenado = struct();
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
            signaltype = app.Estructura_Caracteristicas.signaltype;
            
            %Se lee el vector de opciones           
            Vop = app.Estructura_Caracteristicas.Vector_Caracteristicas;
                           
            
            Clasificador_Entrenado.Clasificador = app.AlgorimoAprendizajeA;
            if(app.RedNeuronalButton.Value ==1)                
                Clasificador_Entrenado.Tipo_Clasificador = 0;                
            else
                Clasificador_Entrenado.Tipo_Clasificador = 1;                
            end
            Clasificador_Entrenado.Caracteristicas.Tipo_Caracteristicas = Tipo_car;
            if (Tipo_car == 1)
                Clasificador_Entrenado.Caracteristicas.Madre = Wmadre;
                Clasificador_Entrenado.Caracteristicas.Descomp = Wn;
            end            
            Clasificador_Entrenado.Vector_op = Vop;
            Clasificador_Entrenado.signaltype = signaltype;
            Clasificador_Entrenado.No_Clases = Tipo_class;  
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Clasificador.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Clasificador_Entrenado')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');

        end

        % Button pushed function: CargarEntrenamientoButton
        function CargarEntrenamientoButtonPushed(app, event)
            
            [file, path] = uigetfile('*.mat', 'Seleccionar entrenamiento');
        
            % Check if the user canceled the file selection
            if file == 0
                return;  % User clicked the Cancel button.
            end
        
            % Load the MAT file
            fullFileName = fullfile(path, file);
            loadedData = load(fullFileName);
        
            % Check if the loaded data contains Clasificador_Entrenado
            if isfield(loadedData, 'Clasificador_Entrenado')
                % The loaded data contains a Clasificador_Entrenado struct
                app.Clasificador_Entrenado = loadedData.Clasificador_Entrenado;
            else 
                disp('Usuario canceló operación');
            end
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
             class = app.clase_prueba;     
            % manejo seleccion de gestos
            if app.ControlautomticoButton.Value == 1
                if class ==1
                    app.qtest = [pi/2, pi/6, pi/3, -pi/2, pi/3, -pi/3]'; 
                elseif class ==2
                    app.qtest = [pi/2, pi/6, pi/3, pi/2, -pi/3, -pi/3]';
                elseif class ==3
                    app.qtest = [pi/2, 0, pi, -pi/2, pi/3, -pi/3]';
                elseif class ==4
                    app.qtest = [0.1, pi/2, 0, pi/2, -pi/3, 0]';
                end
            end 
            
            
            % configuracion inicio
            q0 = zeros(6, 1);
            
            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');

            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end
            
    
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            if app.ManualCheckBox.Value == 1    
                    % Set the number of epochs based on the stored value
                    app.EpochsEditField.Enable = 1;
                    app.LearningRateEditField.Enable = 1;

                else 
                    app.EpochsEditField.Enable = 0;
                    app.LearningRateEditField.Enable = 0;
                end
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            % REAL TIME PLOT
           
           app.continueLoop = true;
           while app.continueLoop
            ax = app.gestonuevofig;
            %%
           
            Features_result_nuevo = struct();
            Matriz_features_gesto_nuevo = 0;
            channel_ventana_gesto_nuevo = 0;
            
            % Define the COM port
            comPort = app.PuertoCOMDropDown.Value;
            baudratePort = app.BaudRateEditField.Value;
            
            
            % Create a serial object
            app.s = serial(comPort, 'BaudRate', baudratePort); % Use the same baud rate as in your Arduino code
            fopen(app.s);


            % Initialize a struct to store the data
            data_com = struct('data', []);
            
            % Flag to indicate whether to collect data
            collectData = false;
            
            % Define a timeout period in seconds
            timeoutInSeconds = app.TmuestraEditField.Value; % Set your desired timeout value here
            
            % Get the current time
            startTime = now;
            
            while true
                % Read one line of data
                dataLine = fgetl(app.s);
            
                % Check for end of data
                if strcmp(dataLine, 'END_DATA')
                    % End of data sequence
                    collectData = false;
                    
                    break; % Exit the loop when "END_DATA" is received

                elseif strcmp(dataLine, 'START_DATA')
                    % Start collecting data
                    collectData = true;
            
                    % Initialize the struct's data field
                    data_com.data = [];
                else
                    % Try to extract and store the data point
                    try
                        dataPoint = str2double(dataLine);
            
                        % Check if dataPoint is a valid number (not NaN)
                        if ~isnan(dataPoint)
                            % Append the data point to the struct's data field
                            data_com.data = [data_com.data, dataPoint];
                           
                        else
                            % Handle the case where the data is not a valid number
                            disp(['Invalid data in line: ', dataLine]);
                        end
                    catch
                        % Handle any errors that occur during data extraction
                        disp(['Error processing line: ', dataLine]);
                    end
                end
               
                % Plot the data after the loop is finished
               plot(ax, data_com.data, 'Color', 'b', 'LineWidth', 1); % Adjust properties as needed
                
                  % Check for a timeout
                currentTime = now;
                if (currentTime - startTime) * 24 * 3600 > timeoutInSeconds
                    disp('Timeout: No data received for the specified time.');
                    break; % Exit the loop on timeout
                end
                
            end
            
            % Display the received data
            disp('Data received:');
            disp(data_com.data);


            app.datosgesto_nuevo = data_com.data;
                
           if app.Clasificador_Entrenado.signaltype == 1 && app.Clasificador_Entrenado.Vector_op(end) == 1
               % ESTE SI REQUIERE DE LA Fs, FRECUENCIA
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV3023(app.datosgesto_nuevo,app.Fs_gesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.Clasificador_Entrenado.Vector_op);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Features_result_nuevo.Matriz_features_nuevo = vector_gesto_nuevo;
               Features_result_nuevo.channel_ventana_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.Clasificador_Entrenado.Vector_op;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
            else
               [vector_gesto_nuevo, ventanadatos_gesto_nuevo, ~] = FeaturesV2023(app.datosgesto_nuevo, 1, length(app.datosgesto_nuevo), 1, app.Clasificador_Entrenado.Vector_op);
               % Hacer algo con las características extraídas de gesto_nuevo, por ejemplo, almacenarlas                
               Features_result_nuevo.Matriz_features_nuevo = vector_gesto_nuevo;
               Features_result_nuevo.channel_ventana_nuevo = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo = vector_gesto_nuevo;                            
               Features_result_nuevo.Car_gesto_nuevo.Caracteristicas = app.Clasificador_Entrenado.Vector_op;
               Features_result_nuevo.Car_gesto_nuevo.canales = 1;
               Features_result_nuevo.Car_gesto_nuevo.data = ventanadatos_gesto_nuevo;
               Features_result_nuevo.Car_gesto_nuevo.Ventana =  length(app.datosgesto_nuevo);
               Features_result_nuevo.Car_gesto_nuevo.Etiquetas_gesto_nuevo =  ones(length(Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo),1);
           end
           
           
           x_prueba = Features_result_nuevo.Car_gesto_nuevo.VectorCaracteristicas_gesto_nuevo;
            net = app.Clasificador_Entrenado.Clasificador;
            
           if app.Clasificador_Entrenado.Tipo_Clasificador == 0    
               % Assuming your loaded network is stored in the variable 'net'
               inputSize = net.inputs{1}.size;
               numFeatures = inputSize;  % Replace with the correct value
               x_prueba = reshape(x_prueba, [numFeatures, 1]);
               y_prueba = net(x_prueba);
               app.clase_prueba = vec2ind(y_prueba);
               app.ClaseEditField.Value = num2str(app.clase_prueba);

           else
               app.clase_prueba = predict(net, x_prueba)
               app.ClaseEditField.Value = num2str(app.clase_prueba);
           end


            class = app.clase_prueba;     
            % manejo seleccion de gestos
            if app.ControlautomticoButton.Value == 1
                if class ==1
                    app.qtest = [pi/2, pi/6, pi/3, -pi/2, pi/3, -pi/3]'; 
                elseif class ==2
                    app.qtest = [pi/2, pi/6, pi/3, pi/2, -pi/3, -pi/3]';
                elseif class ==3
                    app.qtest = [pi/2, 0, pi, -pi/2, pi/3, -pi/3]';
                elseif class ==4
                    app.qtest = [0.1, pi/2, 0, pi/2, -pi/3, 0]';
                end
            end 


            % configuracion inicio
            q0 = zeros(6, 1);


            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');


            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end
      
            % Close the serial connection
            fclose(app.s);

            clear Tdtest app.datosgesto_nuevo Features_result_nuevo Matriz_features_gesto_nuevo channel_ventana_gesto_nuevo data_com currentTime startTime collectData timeoutInSeconds 
            cla(ax);
            % Clear variables
           end
        end

        % Button pushed function: Clase1Gesto
        function Clase1GestoPushed(app, event)
 
            app.qtest = [pi/2, pi/6, pi/3, -pi/2, pi/3, -pi/3]'; 
    
            % configuracion inicio
            q0 = zeros(6, 1);


            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');


            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end


        end

        % Button pushed function: Clase2Gesto
        function Clase2GestoButtonPushed(app, event)
             
            app.qtest = [pi/2, pi/6, pi/3, pi/2, -pi/3, -pi/3]';
    
            % configuracion inicio
            q0 = zeros(6, 1);
            
            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');


            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end
            
        end

        % Button pushed function: Clase3Gesto
        function Clase3GestoButtonPushed(app, event)
             
            app.qtest = [pi/2, 0, pi, -pi/2, pi/3, -pi/3]';
    
            % configuracion inicio
            q0 = zeros(6, 1);
            
            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');


            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end
            
        end

        % Button pushed function: Clase4Gesto
        function Clase4GestoButtonPushed(app, event)
            app.qtest = [0.1, pi/2, 0, pi/2, -pi/3, 0]';             
    
            % configuracion inicio
            q0 = zeros(6, 1);


            % possible fields: {'pinv', 'dampedls', 'transpose'}
            Tdtest = robot_fkine(app.qtest);
            
            % seleccion interactiva
            method_ikine = app.MetodoDropDown.Value;
            [qpos,qpos_hist] = robot_ikine(Tdtest, q0, 'pos', method_ikine);
            
            %% Simulacion
            mdl_puma560;
          
            % Plot on the axes
            plot(app.UIAxes, qpos_hist', 'LineWidth', 1);
            
            ylabel(app.UIAxes, '$\mathbf{q}_k$', 'Interpreter', 'latex', 'FontSize', 18);
            xlabel(app.UIAxes, '$k$', 'Interpreter', 'latex', 'FontSize', 18);
            grid(app.UIAxes, 'minor');


            if app.NormalButton.Value == 1
                % Animación del histórico
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
            else
             
                subplot(1,1,1);
                p560.plot3d(q0');
                pause(0.5);
                p560.plot3d(qpos');
                pause(0.5);
                p560.plot3d(q0');                       
            end
            
        end

        % Selection changed function: TipodecontrolButtonGroup
        function TipodecontrolButtonGroupSelectionChanged(app, event)
            if app.ControlmanualButton.Value == 1
                app.ControlManualPanel.Enable = 'on';
            else 
                app.ControlManualPanel.Enable = 'off';
            end
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
                        % Close the serial connection
            fclose(app.s);
            app.continueLoop = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {690, 690};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.EntrenamientoRNSVMTab = uitab(app.TabGroup2);
            app.EntrenamientoRNSVMTab.Title = 'Entrenamiento RN/SVM';
            app.CargarmatButton = uibutton(app.EntrenamientoRNSVMTab, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar.mat';
            app.SeleccionarclasificadorButtonGroup = uibuttongroup(app.EntrenamientoRNSVMTab);
            app.SeleccionarclasificadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccionarclasificadorButtonGroupSelectionChanged, true);
            app.SeleccionarclasificadorButtonGroup.Title = 'Seleccionar Clasificador';
            app.RedNeuronalButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.RedNeuronalButton.Text = 'Red Neuronal';
            app.RedNeuronalButton.Value = true;
            app.SVMButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.ClasificadorRedNeuronalPanel = uipanel(app.EntrenamientoRNSVMTab);
            app.ClasificadorRedNeuronalPanel.Title = 'Clasificador Red Neuronal';
            app.Label = uilabel(app.ClasificadorRedNeuronalPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.ClasificadorRedNeuronalPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.ClasificadorRedNeuronalPanel);
            app.Label_3.Text = '%';
            app.EntrenamientoEditFieldLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.EntrenamientoEditFieldLabel.Text = 'Entrenamiento';
            app.SegmentosEntrenamiento = uieditfield(app.ClasificadorRedNeuronalPanel, 'numeric');
            app.SegmentosEntrenamiento.Limits = [0 100];
            app.SegmentosEntrenamiento.Value = 70;
            app.SegmentosValidacion = uieditfield(app.ClasificadorRedNeuronalPanel, 'numeric');
            app.SegmentosValidacion.Limits = [0 100];
            app.SegmentosValidacion.Value = 20;
            app.ValidacionEditFieldLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.ValidacionEditFieldLabel.Text = 'Validacion';
            app.SegmentosPrueba = uieditfield(app.ClasificadorRedNeuronalPanel, 'numeric');
            app.SegmentosPrueba.Limits = [0 100];
            app.SegmentosPrueba.Value = 10;
            app.PruebaEditFieldLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.AlgoritmoRNADropDownLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.AlgoritmoRNADropDownLabel.Text = 'Algoritmo RNA';
            app.AlgoritmoparaelentrenamientoDropDown = uidropdown(app.ClasificadorRedNeuronalPanel);
            app.AlgoritmoparaelentrenamientoDropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd'};
            app.AlgoritmoparaelentrenamientoDropDown.Value = 'trainscg';
            app.EpochsEditFieldLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.ClasificadorRedNeuronalPanel, 'numeric');
            app.EpochsEditField.Limits = [1 500];
            app.EpochsEditField.Enable = 'off';
            app.EpochsEditField.Value = 50;
            app.LearningRateEditFieldLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.ClasificadorRedNeuronalPanel, 'numeric');
            app.LearningRateEditField.Limits = [0.0001 0.1];
            app.LearningRateEditField.Enable = 'off';
            app.LearningRateEditField.Value = 0.001;
            app.CapasOcultasDropDownLabel = uilabel(app.ClasificadorRedNeuronalPanel);
            app.CapasOcultasDropDownLabel.Text = 'Capas Ocultas';
            app.CapasOcultasDropDown = uidropdown(app.ClasificadorRedNeuronalPanel);
            app.CapasOcultasDropDown.Items = {'2', '4', '8', '10', '12', '15'};
            app.CapasOcultasDropDown.Value = '2';
            app.ManualCheckBox = uicheckbox(app.ClasificadorRedNeuronalPanel);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.MquinadeSoporteVectorialSVMPanel = uipanel(app.EntrenamientoRNSVMTab);
            app.MquinadeSoporteVectorialSVMPanel.Title = 'Máquina de Soporte Vectorial (SVM)';
            app.ValidacinCruzadaLabel = uilabel(app.MquinadeSoporteVectorialSVMPanel);
            app.ValidacinCruzadaLabel.Text = 'Validación Cruzada: ';
            app.KfoldsLabel = uilabel(app.MquinadeSoporteVectorialSVMPanel);
            app.KfoldsLabel.Text = 'K-folds';
            app.NmerodeParticionesKSpinner = uispinner(app.MquinadeSoporteVectorialSVMPanel);
            app.NmerodeParticionesKSpinner.Limits = [2 10];
            app.NmerodeParticionesKSpinner.Enable = 'off';
            app.NmerodeParticionesKSpinner.Value = 2;
            app.KernelDropDownLabel = uilabel(app.MquinadeSoporteVectorialSVMPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelaemplearDropDown = uidropdown(app.MquinadeSoporteVectorialSVMPanel);
            app.KernelaemplearDropDown.Items = {'Gaussian', 'Linear', ''};
            app.KernelaemplearDropDown.Enable = 'off';
            app.KernelaemplearDropDown.Value = 'Gaussian';
            app.EntrenarButton = uibutton(app.EntrenamientoRNSVMTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.GuardarButton = uibutton(app.EntrenamientoRNSVMTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ExactituddelclasificadorLabel = uilabel(app.EntrenamientoRNSVMTab);
            app.ExactituddelclasificadorLabel.Text = 'Exactitud Clasificador:  ?';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.InterfazdecontrolrobticoTab = uitab(app.TabGroup);
            app.InterfazdecontrolrobticoTab.Title = 'Interfaz de control robótico';
            app.ControlOfflinePanel = uipanel(app.InterfazdecontrolrobticoTab);
            app.ControlOfflinePanel.Title = 'Control Offline';
            app.CargarsegmentoButton = uibutton(app.ControlOfflinePanel, 'push');
            app.CargarsegmentoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarsegmentoButtonPushed, true);
            app.CargarsegmentoButton.Text = 'Cargar segmento';
            app.SimularButton = uibutton(app.ControlOfflinePanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ClaseEditFieldLabel = uilabel(app.InterfazdecontrolrobticoTab);
            app.ClaseEditFieldLabel.Text = 'Clase';
            app.ClaseEditField = uieditfield(app.InterfazdecontrolrobticoTab, 'text');
            app.CargarEntrenamientoButton = uibutton(app.InterfazdecontrolrobticoTab, 'push');
            app.CargarEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEntrenamientoButtonPushed, true);
            app.CargarEntrenamientoButton.Text = 'Cargar Entrenamiento';
            app.TipodecontrolButtonGroup = uibuttongroup(app.InterfazdecontrolrobticoTab);
            app.TipodecontrolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodecontrolButtonGroupSelectionChanged, true);
            app.TipodecontrolButtonGroup.Title = 'Tipo de control';
            app.ControlautomticoButton = uiradiobutton(app.TipodecontrolButtonGroup);
            app.ControlautomticoButton.Text = 'Control automático';
            app.ControlautomticoButton.Value = true;
            app.ControlmanualButton = uiradiobutton(app.TipodecontrolButtonGroup);
            app.ControlmanualButton.Text = 'Control manual';
            app.ControlSerialPanel = uipanel(app.InterfazdecontrolrobticoTab);
            app.ControlSerialPanel.Title = 'Control - Serial';
            app.BaudRateEditFieldLabel = uilabel(app.ControlSerialPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.ControlSerialPanel, 'numeric');
            app.BaudRateEditField.Value = 38400;
            app.PuertoCOMDropDownLabel = uilabel(app.ControlSerialPanel);
            app.PuertoCOMDropDownLabel.Text = 'Puerto COM';
            app.PuertoCOMDropDown = uidropdown(app.ControlSerialPanel);
            app.PuertoCOMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10'};
            app.PuertoCOMDropDown.Value = 'COM3';
            app.ConectarButton = uibutton(app.ControlSerialPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar ';
            app.DesconectarButton = uibutton(app.ControlSerialPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.TmuestraEditFieldLabel = uilabel(app.ControlSerialPanel);
            app.TmuestraEditFieldLabel.Text = 'T. muestra';
            app.TmuestraEditField = uieditfield(app.ControlSerialPanel, 'numeric');
            app.TmuestraEditField.Value = 5;
            app.HistricoP560Panel = uipanel(app.InterfazdecontrolrobticoTab);
            app.HistricoP560Panel.Title = 'Histórico P560';
            app.UIAxes = uiaxes(app.HistricoP560Panel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ActividadPanel = uipanel(app.InterfazdecontrolrobticoTab);
            app.ActividadPanel.Title = 'Actividad';
            app.gestonuevofig = uiaxes(app.ActividadPanel);
            xlabel(app.gestonuevofig, 'X')
            ylabel(app.gestonuevofig, 'Y')
            zlabel(app.gestonuevofig, 'Z')
            app.ControlManualPanel = uipanel(app.InterfazdecontrolrobticoTab);
            app.ControlManualPanel.Enable = 'off';
            app.ControlManualPanel.Title = 'Control Manual';
            app.Clase1Gesto = uibutton(app.ControlManualPanel, 'push');
            app.Clase1Gesto.ButtonPushedFcn = createCallbackFcn(app, @Clase1GestoPushed, true);
            app.Clase1Gesto.Text = '1';
            app.Clase2Gesto = uibutton(app.ControlManualPanel, 'push');
            app.Clase2Gesto.ButtonPushedFcn = createCallbackFcn(app, @Clase2GestoButtonPushed, true);
            app.Clase2Gesto.Text = '2';
            app.Clase3Gesto = uibutton(app.ControlManualPanel, 'push');
            app.Clase3Gesto.ButtonPushedFcn = createCallbackFcn(app, @Clase3GestoButtonPushed, true);
            app.Clase3Gesto.Text = '3';
            app.Clase4Gesto = uibutton(app.ControlManualPanel, 'push');
            app.Clase4Gesto.ButtonPushedFcn = createCallbackFcn(app, @Clase4GestoButtonPushed, true);
            app.Clase4Gesto.Text = '4';
            app.MetodoDropDownLabel = uilabel(app.ControlManualPanel);
            app.MetodoDropDownLabel.Text = 'Metodo';
            app.MetodoDropDown = uidropdown(app.ControlManualPanel);
            app.MetodoDropDown.Items = {'pinv', 'dampedls', 'transpose'};
            app.MetodoDropDown.Value = 'transpose';
            app.TrayectoriaButtonGroup = uibuttongroup(app.InterfazdecontrolrobticoTab);
            app.TrayectoriaButtonGroup.Title = 'Trayectoria';
            app.NormalButton = uiradiobutton(app.TrayectoriaButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.PickandPlaceButton = uiradiobutton(app.TrayectoriaButtonGroup);
            app.PickandPlaceButton.Text = 'Pick and Place';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entrenamientos4clases

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end