classdef recoleccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup6                    matlab.ui.container.TabGroup
        GestosTab                    matlab.ui.container.Tab
        CantidaddeclasesButtonGroup  matlab.ui.container.ButtonGroup
        clases4                      matlab.ui.control.RadioButton
        clases3                      matlab.ui.control.RadioButton
        clases2                      matlab.ui.control.RadioButton
        TipodeanlisisButtonGroup     matlab.ui.container.ButtonGroup
        ElectroencefalografaButton   matlab.ui.control.RadioButton
        ElectromiografaButton        matlab.ui.control.RadioButton
        Gesto4Panel                  matlab.ui.container.Panel
        gesto4fs                     matlab.ui.control.Label
        gesto4time                   matlab.ui.control.Label
        gesto4size                   matlab.ui.control.Label
        gesto4button                 matlab.ui.control.Button
        Gesto3Panel                  matlab.ui.container.Panel
        gesto3fs                     matlab.ui.control.Label
        gesto3time                   matlab.ui.control.Label
        gesto3size                   matlab.ui.control.Label
        gesto3button                 matlab.ui.control.Button
        Gesto2Panel                  matlab.ui.container.Panel
        gesto2fs                     matlab.ui.control.Label
        gesto2time                   matlab.ui.control.Label
        gesto2size                   matlab.ui.control.Label
        gesto2button                 matlab.ui.control.Button
        Gesto1Panel                  matlab.ui.container.Panel
        gesto1fs                     matlab.ui.control.Label
        gesto1time                   matlab.ui.control.Label
        gesto1size                   matlab.ui.control.Label
        gesto1button                 matlab.ui.control.Button
        CapturarBiopacTab            matlab.ui.container.Tab
        SeleccionarplantillasxlsxyextraerPanel  matlab.ui.container.Panel
        ExtraermatsButton            matlab.ui.control.Button
        CapturarconBiopacygenerarxlsxPanel  matlab.ui.container.Panel
        CrearPlantillaxlsxButton     matlab.ui.control.Button
        IniciarBSLAnalysisButton     matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        TabGroup7                    matlab.ui.container.TabGroup
        SegmentacionTab              matlab.ui.container.Tab
        Gesto4Segmentacion           matlab.ui.container.Panel
        sLabel_4                     matlab.ui.control.Label
        Segmentar4Button             matlab.ui.control.Button
        minimo4                      matlab.ui.control.NumericEditField
        LEditField_3Label            matlab.ui.control.Label
        extraergesto4                matlab.ui.control.Button
        Ventana4Spinner              matlab.ui.control.Spinner
        VentanaSpinnerLabel_4        matlab.ui.control.Label
        Alpha4Spinner                matlab.ui.control.Spinner
        KLabel_4                     matlab.ui.control.Label
        Gesto3Segmentacion           matlab.ui.container.Panel
        sLabel_3                     matlab.ui.control.Label
        Segmentar3Button             matlab.ui.control.Button
        minimo3                      matlab.ui.control.NumericEditField
        LEditField_4Label            matlab.ui.control.Label
        extraergesto3                matlab.ui.control.Button
        Ventana3Spinner              matlab.ui.control.Spinner
        VentanaSpinnerLabel_3        matlab.ui.control.Label
        Alpha3Spinner                matlab.ui.control.Spinner
        KLabel_3                     matlab.ui.control.Label
        Gesto2Segmentacion           matlab.ui.container.Panel
        sLabel_2                     matlab.ui.control.Label
        Segmentar2Button             matlab.ui.control.Button
        minimo2                      matlab.ui.control.NumericEditField
        LEditField_2Label            matlab.ui.control.Label
        extraergesto2                matlab.ui.control.Button
        Ventana2Spinner              matlab.ui.control.Spinner
        VentanaSpinnerLabel_2        matlab.ui.control.Label
        Alpha2Spinner                matlab.ui.control.Spinner
        KLabel_2                     matlab.ui.control.Label
        Gesto1Segmentacion           matlab.ui.container.Panel
        sLabel                       matlab.ui.control.Label
        Segmentar1Button             matlab.ui.control.Button
        minimo1                      matlab.ui.control.NumericEditField
        LEditFieldLabel              matlab.ui.control.Label
        extraergesto1                matlab.ui.control.Button
        Ventana1Spinner              matlab.ui.control.Spinner
        VentanaSpinnerLabel          matlab.ui.control.Label
        Alpha1Spinner                matlab.ui.control.Spinner
        KLabel                       matlab.ui.control.Label
        SegmentarmatexternoTab       matlab.ui.container.Tab
        SegmentarButton_2            matlab.ui.control.Button
        ExtraersegmentosButton       matlab.ui.control.Button
        GestoSegmentacion            matlab.ui.container.Panel
        minimo                       matlab.ui.control.NumericEditField
        LEditField_2Label_2          matlab.ui.control.Label
        CargarmatButton              matlab.ui.control.Button
        VentanaSpinner               matlab.ui.control.Spinner
        VentanaSpinnerLabel_5        matlab.ui.control.Label
        KSpinner                     matlab.ui.control.Spinner
        KSpinnerLabel                matlab.ui.control.Label
        TabGroup3                    matlab.ui.container.TabGroup
        Gesto1Tab                    matlab.ui.container.Tab
        segmentosgesto1              matlab.ui.control.NumericEditField
        SegmentosdeactividaddetectadosEditFieldLabel  matlab.ui.control.Label
        gesto1fig                    matlab.ui.control.UIAxes
        Gesto2Tab                    matlab.ui.container.Tab
        segmentosgesto2              matlab.ui.control.NumericEditField
        SegmentosdeactividaddetectadosEditField_2Label  matlab.ui.control.Label
        gesto2fig                    matlab.ui.control.UIAxes
        Gesto3Tab                    matlab.ui.container.Tab
        segmentosgesto3              matlab.ui.control.NumericEditField
        SegmentosdeactividaddetectadosEditField_3Label  matlab.ui.control.Label
        gesto3fig                    matlab.ui.control.UIAxes
        Gesto4Tab                    matlab.ui.container.Tab
        segmentosgesto4              matlab.ui.control.NumericEditField
        SegmentosdeactividaddetectadosEditField_4Label  matlab.ui.control.Label
        gesto4fig                    matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        CaractersticasButtonGroup    matlab.ui.container.ButtonGroup
        FrecuenciaButton             matlab.ui.control.RadioButton
        TiempoContinuoButton         matlab.ui.control.RadioButton
        ExtraerMatrizdeCaractersticasButton  matlab.ui.control.Button
        FrecuenciaPanel              matlab.ui.container.Panel
        SeleccionartodoCheckBox_2    matlab.ui.control.CheckBox
        StdFreqCheckBox              matlab.ui.control.CheckBox
        ratio_5CheckBox              matlab.ui.control.CheckBox
        ratio_3CheckBox              matlab.ui.control.CheckBox
        ratio_1CheckBox              matlab.ui.control.CheckBox
        ratio_2CheckBox              matlab.ui.control.CheckBox
        ratio_4CheckBox              matlab.ui.control.CheckBox
        TiempoContinuoPanel          matlab.ui.container.Panel
        TCDesviacinEstndarCheckBox   matlab.ui.control.CheckBox
        TCEnergaAcumuladaCheckBox    matlab.ui.control.CheckBox
        TCCurtosisCheckBox           matlab.ui.control.CheckBox
        TCValorMedioAbsolutoMAVCheckBox  matlab.ui.control.CheckBox
        TCCrucesporceroZCCheckBox    matlab.ui.control.CheckBox
        SeleccionartodoCheckBox      matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
       % variables globales  
       gesto_segmentar
       gesto_segmentar_1
       gesto_segmentar_2
       gesto_segmentar_3
       gesto_segmentar_4
       datosgesto
       datosgesto1
       datosgesto2
       datosgesto3
       datosgesto4
       gestoFs
       gesto1Fs
       gesto2Fs
       gesto3Fs
       gesto4Fs
       gestofilter
       gesto1filter
       gesto2filter
       gesto3filter
       gesto4filter
       segment_start_gesto
       segment_start_gesto_1
       segment_start_gesto_2
       segment_start_gesto_3
       segment_start_gesto_4
       segment_end_gesto
       segment_end_gesto_1
       segment_end_gesto_2
       segment_end_gesto_3
       segment_end_gesto_4      
       Matriz_features_gesto_1
       Matriz_features_gesto_2 
       Matriz_features_gesto_3
       Matriz_features_gesto_4          
       channel_ventana_gesto_1
       channel_ventana_gesto_2
       channel_ventana_gesto_3
       channel_ventana_gesto_4                    
       c_gesto_1
       c_gesto_2
       c_gesto_3 
       c_gesto_4
       op 
       canales
       ClassType
       window_size1
       window_size2
       window_size3
       window_size4
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: TipodeanlisisButtonGroup
        function TipodeanlisisButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
            if selectedButton == app.ElectromiografaButton 
                app.FrecuenciaPanel.Visible = 'off'; 
                app.CaractersticasButtonGroup.Enable = 'off';
                app.CaractersticasButtonGroup.Visible = 'off';
                app.TiempoContinuoPanel.Enable = 'on';

               
            elseif selectedButton == app.ElectroencefalografaButton
                app.FrecuenciaPanel.Visible = 'on';
                app.CaractersticasButtonGroup.Enable = 'on';
                app.CaractersticasButtonGroup.Visible = 'on';
            end
        end

        % Selection changed function: CantidaddeclasesButtonGroup
        function CantidaddeclasesButtonGroupSelectionChanged(app, event)
            app.ExtraerMatrizdeCaractersticasButton.Enable = 'off';

            selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;
            if selectedButton == app.clases2 
                app.Gesto3Panel.Visible = 'off';
                app.Gesto4Panel.Visible = 'off';
                app.Gesto3Panel.Enable = 'off';
                app.Gesto4Panel.Enable = 'off';
                app.Gesto3Segmentacion.Enable = 'off';
                app.Gesto4Segmentacion.Enable = 'off';
                app.Gesto3Segmentacion.Visible = 'off';
                app.Gesto4Segmentacion.Visible = 'off';
            elseif selectedButton == app.clases3
                app.Gesto3Panel.Visible = 'on';
                app.Gesto4Panel.Visible = 'off';
                app.Gesto3Panel.Enable = 'on';
                app.Gesto4Panel.Enable = 'off';
                app.Gesto3Segmentacion.Enable = 'on';
                app.Gesto4Segmentacion.Enable = 'off';
                app.Gesto3Segmentacion.Visible = 'on';
                app.Gesto4Segmentacion.Visible = 'off';
            elseif selectedButton == app.clases4
                app.Gesto3Panel.Visible = 'on';
                app.Gesto3Panel.Enable = 'on';
                app.Gesto4Panel.Visible = 'on';
                app.Gesto4Panel.Enable = 'on';
                app.Gesto3Segmentacion.Enable = 'on';
                app.Gesto4Segmentacion.Enable = 'on';
                app.Gesto3Segmentacion.Visible = 'on';
                app.Gesto4Segmentacion.Visible = 'on';
            end
            
        end

        % Button pushed function: gesto1button
        function gesto1buttonButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable 
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal EMG/EEG a la variable de datos respectiva
                app.gesto_segmentar_1 = workspace.gesto;
                app.datosgesto1 = workspace.gesto.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.gesto1Fs = workspace.gesto.sampling_frequency;
                datasize = size(app.datosgesto1);
                %Se indica en el texto el tamaño y duración de los datos a emplear
                app.gesto1fs.Text = append("Freq. Muestreo (Fs): ",num2str(app.gesto1Fs), "Hz");
                app.gesto1size.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto.data_length_sec;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.gesto1time.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.gesto1time.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.gesto1time.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.gesto1time.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end           
            
            ylim(app.gesto1fig, [min(app.datosgesto1) max(app.datosgesto1)]);
            Epocas = size(app.datosgesto1,2) ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, (Epocas - 1) / app.gesto1Fs, Epocas);
            
            h1 = plot(app.gesto1fig, t, app.datosgesto1, 'Color','r') %se plotea esa muestra
            end
            
            % Get a handle to the target tab group
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto1Tab; % Replace with the actual tab name
        end

        % Button pushed function: gesto2button
        function gesto2buttonButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable 
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal EMG/EEG a la variable de datos respectiva
                app.gesto_segmentar_2 = workspace.gesto;
                app.datosgesto2 = workspace.gesto.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.gesto2Fs = workspace.gesto.sampling_frequency;
                datasize = size(app.datosgesto2);

                %Se indica en el texto el tamaño y duración de los datos a emplear
                app.gesto2fs.Text = append("Freq. Muestreo (Fs): ",num2str(app.gesto2Fs), "Hz");
                app.gesto2size.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.gesto2time.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.gesto2time.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.gesto2time.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.gesto2time.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.gesto2fig, [min(app.datosgesto2) max(app.datosgesto2)]);
             Epocas = size(app.datosgesto2,2) ; %se toma el tamano del buffer obtenido
             L = Epocas; %longitud del vector epocas
            t=linspace(0, (Epocas - 1) / app.gesto2Fs, Epocas);
            h1 = plot(app.gesto2fig, t, app.datosgesto2, 'Color','g') %se plotea esa muestra
            end            
            targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto2Tab; % Replace with the actual tab name
            
        end

        % Button pushed function: gesto3button
        function gesto3buttonButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable 
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal EMG/EEG a la variable de datos respectiva
                app.gesto_segmentar_3 = workspace.gesto;
                app.datosgesto3 = workspace.gesto.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.gesto3Fs = workspace.gesto.sampling_frequency;
                datasize = size(app.datosgesto3);
                %Se indica en el texto el tamaño y duración de los datos a emplear
                app.gesto3fs.Text = append("Freq. Muestreo (Fs): ",num2str(app.gesto3Fs), "Hz");
                app.gesto3size.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.gesto3time.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.gesto3time.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.gesto3time.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.gesto3time.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
                ylim(app.gesto3fig, [min(app.datosgesto3) max(app.datosgesto3)]);
             Epocas = size(app.datosgesto3,2) ; %se toma el tamano del buffer obtenido
             L = Epocas; %longitud del vector epocas
            t=linspace(0, (Epocas - 1) / app.gesto3Fs, Epocas);
            h1 = plot(app.gesto3fig, t, app.datosgesto3, 'Color','b') %se plotea esa muestra
            end            
           targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto3Tab; % Replace with the actual tab name
        end

        % Button pushed function: gesto4button
        function gesto4buttonButtonPushed(app, event)
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo .mat');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable 
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal EMG/EEG a la variable de datos respectiva
                app.gesto_segmentar_4 = workspace.gesto;
                app.datosgesto4 = workspace.gesto.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.gesto4Fs = workspace.gesto.sampling_frequency;
                datasize = size(app.datosgesto4);
                %Se indica en el texto el tamaño y duración de los datos a emplear
                app.gesto4fs.Text = append("Freq. Muestreo (Fs): ",num2str(app.gesto4Fs), "Hz");

                app.gesto4size.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.gesto.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.gesto4time.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.gesto4time.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.gesto4time.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.gesto4time.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
            ylim(app.gesto4fig, [min(app.datosgesto4) max(app.datosgesto4)]);
            Epocas = size(app.datosgesto4,2) ; %se toma el tamano del buffer obtenido
            L = Epocas; %longitud del vector epocas
            t=linspace(0, (Epocas - 1) / app.gesto4Fs, Epocas);
            h1 = plot(app.gesto4fig, t, app.datosgesto4, 'Color','magenta') %se plotea esa muestra
            end            
           targetTabGroup = app.TabGroup3; % Replace with the actual name
            
            % Set the SelectedTab property to the desired tab
            targetTabGroup.SelectedTab = app.Gesto4Tab; % Replace with the actual tab name
        end

        % Button pushed function: IniciarBSLAnalysisButton
        function IniciarBSLAnalysisButtonPushed(app, event)
            folderPath = 'C:\Program Files\BIOPAC Systems, Inc\BSL Analysis 4.1';            
            exeName = 'BSL Analysis 4.1.exe';
            
            exePath = fullfile(folderPath, exeName);
            
            system(exePath);
        end

        % Button pushed function: CrearPlantillaxlsxButton
        function CrearPlantillaxlsxButtonPushed(app, event)
            [filename, path] = uiputfile('*.xlsx', 'Save Excel Template As');
            
            if isequal(filename,0) || isequal(path,0)
                return;
            end
            
            templateFilename = fullfile(path, filename);
            
            % Create a cell array with the content
            templateContent = {'data', 'data_length_sec', 'sampling_frequency', 'channels'};
            xlswrite(templateFilename, templateContent);
            
            % Open the Excel file using winopen
            winopen(templateFilename);

        end

        % Button pushed function: ExtraermatsButton
        function ExtraermatsButtonPushed(app, event)
            % Display an alert message
            alertMessage = 'Este proceso puede tomar un momento...';
            h = msgbox(alertMessage, 'Alerta', 'warn');
            
            % Get the paths of the two .xlsx files using uigetfile dialog
            
            [file1, path1] = uigetfile('*.xlsx', 'Seleccione el archivo .xlsx');
    
            % Check if the user canceled the file selection
            if isequal(file1, 0)
                % Handle the case where the user canceled the file selection
                return; % Exit the function
            end
    
            % Create the full file paths
            filePath1 = fullfile(path1, file1);
    
            % Call the autoextract_func_inter function with the file paths as arguments
            autoextract_func_inter(filePath1);
            %Mensaje de exito 
            message = sprintf(['Archivo guardado exitosamente']);
            uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
        end

        % Button pushed function: Segmentar1Button
        function Segmentar1ButtonPushed(app, event)
            % Variables de segmentacion
            alpha1 = app.Alpha1Spinner.Value;
            % Assume the user inputs the window size in seconds
            window_size_seconds = app.Ventana1Spinner.Value;
            % Convert window size to samples based on the sampling frequency (Fs)
            app.window_size1 = round(window_size_seconds * app.gesto1Fs);
            
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;

            if selectedButton2 == app.ElectromiografaButton
                %% Segmentation for gesto1
                [~, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1] = SegmentV2(app.datosgesto1, app.gesto1Fs,alpha1, app.minimo1.Value,app.window_size1, 1);
                app.extraergesto1.Enable = 'on';
           elseif selectedButton2 == app.ElectroencefalografaButton
                %% Segmentation for gesto1
                [~, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1] = SegmentV2EEG(app.datosgesto1, app.gesto1Fs,alpha1, app.minimo1.Value,app.window_size1, 1);
                app.extraergesto1.Enable = 'on';              
            end
            
            
            app.segmentosgesto1.Value = length(app.segment_start_gesto_1);
            selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;

            if selectedButton== app.clases2 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases3 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases4 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on') && strcmp(app.extraergesto4.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            else 
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'off';
            end
        end

        % Button pushed function: ExtraersegmentosButton
        function ExtraersegmentosButtonPushed(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
           
           if selectedButton == app.ElectromiografaButton
                    % Call the segment_auto_func function with the data as arguments
                    [~] = segment_auto_funcV2(app.gesto_segmentar, app.KSpinner.Value, app.VentanaSpinner.Value);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
                    
                elseif selectedButton == app.ElectroencefalografaButton
                    [~] = segment_auto_funcV2EEG(app.gesto_segmentar, app.KSpinner.Value, app.VentanaSpinner.Value);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
           end
            
            
        end

        % Value changed function: SeleccionartodoCheckBox
        function SeleccionartodoCheckBoxValueChanged(app, event)
            value = app.SeleccionartodoCheckBox.Value;
            if app.SeleccionartodoCheckBox.Value ==1

                app.TCDesviacinEstndarCheckBox.Value=1;
                app.TCEnergaAcumuladaCheckBox.Value=1;
                app.TCCurtosisCheckBox.Value=1;
                app.TCValorMedioAbsolutoMAVCheckBox.Value=1;
                app.TCCrucesporceroZCCheckBox.Value=1;

            end
            if app.SeleccionartodoCheckBox.Value ==0
                
                app.TCDesviacinEstndarCheckBox.Value=0;
                app.TCEnergaAcumuladaCheckBox.Value=0;
                app.TCCurtosisCheckBox.Value=0;
                app.TCValorMedioAbsolutoMAVCheckBox.Value=0;
                app.TCCrucesporceroZCCheckBox.Value=0;
            end
        end

        % Value changed function: SeleccionartodoCheckBox_2
        function SeleccionartodoCheckBox_2ValueChanged(app, event)
            value = app.SeleccionartodoCheckBox_2.Value;
            if app.SeleccionartodoCheckBox_2.Value ==1

                app.ratio_5CheckBox.Value=1;
                app.ratio_4CheckBox.Value=1;
                app.ratio_3CheckBox.Value=1;
                app.ratio_2CheckBox.Value=1;
                app.ratio_1CheckBox.Value=1;
                app.StdFreqCheckBox.Value=1;

            end
            if app.SeleccionartodoCheckBox_2.Value ==0
                
                app.ratio_5CheckBox.Value=0;
                app.ratio_4CheckBox.Value=0;
                app.ratio_3CheckBox.Value=0;
                app.ratio_2CheckBox.Value=0;
                app.ratio_1CheckBox.Value=0;
                app.StdFreqCheckBox.Value=0;
            end
        end

        % Button pushed function: 
        % ExtraerMatrizdeCaractersticasButton
        function ExtraerMatrizdeCaractersticasButtonPushed(app, event)
            selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;
            selectedButton3 = app.CaractersticasButtonGroup.SelectedObject;

            Features_result = struct();
            
            if selectedButton == app.clases2
                Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [];
                Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [];
                
                Features_result.Car_gesto_1.data = [];
                Features_result.Car_gesto_2.data = [];
                app.Matriz_features_gesto_1 = 0;
                app.Matriz_features_gesto_2 = 0;
            
                app.channel_ventana_gesto_1 = 0;
                app.channel_ventana_gesto_2 = 0;
            
            elseif selectedButton == app.clases3
                Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [];
                Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [];
                Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [];
                
                Features_result.Car_gesto_1.data = [];
                Features_result.Car_gesto_2.data = [];
                Features_result.Car_gesto_3.data = [];
                app.Matriz_features_gesto_1 = 0;
                app.Matriz_features_gesto_2 = 0;
                app.Matriz_features_gesto_3 = 0;
                
                app.channel_ventana_gesto_1 = 0;
                app.channel_ventana_gesto_2 = 0;
                app.channel_ventana_gesto_3 = 0;


            elseif selectedButton ==app.clases4
                Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [];
                Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [];
                Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [];
                Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4 = [];
                
                Features_result.Car_gesto_1.data = [];
                Features_result.Car_gesto_2.data = [];
                Features_result.Car_gesto_3.data = [];
                Features_result.Car_gesto_4.data = [];    
                app.Matriz_features_gesto_1 = 0;
                app.Matriz_features_gesto_2 = 0;
                app.Matriz_features_gesto_3 = 0;
                app.Matriz_features_gesto_4 = 0;
                
                app.channel_ventana_gesto_1 = 0;
                app.channel_ventana_gesto_2 = 0;
                app.channel_ventana_gesto_3 = 0;
                app.channel_ventana_gesto_4 = 0;
                
            end
                        
            app.c_gesto_1 = 1; 
            app.c_gesto_2 = 1;
            app.c_gesto_3 = 1;
            app.c_gesto_4 = 1;
            %Se elige si es analisis de TC
            app.op =[0,0,0,0,0,0];          
            app.canales = 1; %Por el momento, sólo se usa 1 canal de cada señal    
            
            if selectedButton2 == app.ElectromiografaButton
                seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';seleccion5 = '';
                if app.TCDesviacinEstndarCheckBox.Value
                    app.op(1) =1;
                    seleccion1= 'DesviaciónEstándar'; 
                end
                if app.TCCurtosisCheckBox.Value
                    app.op(2) =1;
                    seleccion2= 'Curtosis'; 
                end
                if app.TCCrucesporceroZCCheckBox.Value
                    app.op(3) =1;
                    seleccion3= 'CrucesPorCero'; 
                end
                if app.TCValorMedioAbsolutoMAVCheckBox.Value
                    app.op(4) =1;
                    seleccion4= 'ValorMedioAbsoluto'; 
                end
                if app.TCEnergaAcumuladaCheckBox.Value
                   app.op(5) =1;
                   seleccion5= 'EnergíaAcumulada'; 
                end
            
            
            elseif selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.FrecuenciaButton
                seleccion1='';seleccion2='';seleccion3='';seleccion4='';seleccion5='';seleccion6='';
                if app.ratio_1CheckBox.Value % θ/β
                    app.op(1)=1;
                    seleccion1 = 'Ratio1';
                end
                if app.ratio_2CheckBox.Value % β/α
                    app.op(2)=1;
                    seleccion2 = 'Ratio2';
                end
                if app.ratio_3CheckBox.Value % (θ + α)/β
                    app.op(3)=1;
                    seleccion3 = 'Ratio3';
                end
                if app.ratio_4CheckBox.Value % θ/α
                    app.op(4)=1;
                    seleccion4 = 'Ratio4';
                end
                if app.ratio_5CheckBox.Value % (θ + α)/(α + β)
                    app.op(5)=1;
                    seleccion5 = 'Ratio5';
                end
                if app.StdFreqCheckBox.Value % std
                    app.op(6)=1;
                    seleccion6 = 'Std';
                end
        
           elseif selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.TiempoContinuoButton
                seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';seleccion5 = '';
                if app.TCDesviacinEstndarCheckBox.Value
                    app.op(1) =1;
                    seleccion1= 'DesviaciónEstándar'; 
                end
                if app.TCCurtosisCheckBox.Value
                    app.op(2) =1;
                    seleccion2= 'Curtosis'; 
                end
                if app.TCCrucesporceroZCCheckBox.Value
                    app.op(3) =1;
                    seleccion3= 'CrucesPorCero'; 
                end
                if app.TCValorMedioAbsolutoMAVCheckBox.Value
                    app.op(4) =1;
                    seleccion4= 'ValorMedioAbsoluto'; 
                end
                if app.TCEnergaAcumuladaCheckBox.Value
                   app.op(5) =1;
                   seleccion5= 'EnergíaAcumulada'; 
                end
            end
        
             % Variables de segmentacion
            alpha1 = app.Alpha1Spinner.Value;
            alpha2 = app.Alpha2Spinner.Value;
            alpha3 = app.Alpha3Spinner.Value;
            alpha4 = app.Alpha4Spinner.Value;


            if selectedButton == app.clases2 && selectedButton2 == app.ElectromiografaButton
                app.ClassType = 2;
                Features_result.signaltype = 0;
                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2(app.datosgesto1, app.gesto1Fs, alpha1, app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2(app.datosgesto2, app.gesto2Fs, alpha2, app.minimo2.Value, app.window_size2,0);


            %% PROCESAMIENTO 2 GESTOS
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1
                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                 
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_2
                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
            
            elseif selectedButton == app.clases3 && selectedButton2 == app.ElectromiografaButton
                app.ClassType = 3;
                Features_result.signaltype = 0;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2(app.datosgesto1, app.gesto1Fs, alpha1, app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2(app.datosgesto2, app.gesto2Fs, alpha2, app.minimo2.Value, app.window_size2,0);
                % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2(app.datosgesto3, app.gesto3Fs, alpha3, app.minimo3.Value, app.window_size3,0);


            %% PROCESAMIENTO 3 GESTOS
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1
                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                 
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_2
                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end  
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para
                    % gesto_3
                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV2023(segmentos_3(i,:), app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_3, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end


            elseif selectedButton == app.clases4 && selectedButton2 == app.ElectromiografaButton
                 app.ClassType = 4;
                 Features_result.signaltype = 0;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2(app.datosgesto1, app.gesto1Fs, alpha1, app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2(app.datosgesto2, app.gesto2Fs, alpha2, app.minimo2.Value, app.window_size2,0);
                % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2(app.datosgesto3, app.gesto3Fs, alpha3, app.minimo3.Value, app.window_size3,0);
                % Segmentation gesto4
                [segmentos_4, app.gesto4filter, app.segment_start_gesto_4, app.segment_end_gesto_4]= SegmentV2(app.datosgesto4, app.gesto4Fs, alpha4, app.minimo4.Value, app.window_size4,0);


            %% PROCESAMIENTO 4 GESTOS
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1
                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                 
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_2
                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_2, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end  
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para
                    % gesto_3
                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV2023(segmentos_3(i,:), app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_3, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_4,1)
                    % Extraer características utilizando FeaturesV2 para
                    % gesto_4
                    [vector_gesto_4, ventanadatos_gesto_4, ~] = FeaturesV2023(segmentos_4(i,:), app.canales, size(segmentos_4,2), app.c_gesto_4, app.op);
                   
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_4 = vector_gesto_4;
                    app.channel_ventana_gesto_4 = ventanadatos_gesto_4;
                    Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4 = [Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4; vector_gesto_4];
                    Features_result.Car_gesto_4.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_4.canales = app.c_gesto_1;
                    Features_result.Car_gesto_4.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_4.FMuestreo = app.gesto4Fs;
                    Features_result.Car_gesto_4.Ventana =  size(segmentos_4,2);
                    Features_result.Car_gesto_4.Etiquetas_gesto_4 = 4*ones(length(Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
            %% AQUI COMIENZA EEG
            %% FRECUENCIA - EEG
            
            elseif selectedButton == app.clases2 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.FrecuenciaButton
                app.ClassType = 2;
                Features_result.signaltype = 1;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
                
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV3023(segmentos_1(i,:), app.gesto1Fs, app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV3023(segmentos_2(i,:), app.gesto2Fs, app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end


            elseif selectedButton == app.clases3 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.FrecuenciaButton
                 app.ClassType = 3;
                 Features_result.signaltype = 1;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
                 % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2EEG(app.datosgesto3, app.gesto3Fs, alpha3,app.minimo3.Value, app.window_size3,0);
                
            
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV3023(segmentos_1(i,:), app.gesto1Fs, app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV3023(segmentos_2(i,:), app.gesto2Fs, app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV3023(segmentos_3(i,:), app.gesto3Fs, app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end


            elseif selectedButton == app.clases4 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.FrecuenciaButton
                app.ClassType = 4;
                Features_result.signaltype = 1;
                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
                 % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2EEG(app.datosgesto3, app.gesto3Fs, alpha3,app.minimo3.Value, app.window_size3,0);
                 % Segmentation gesto4
                [segmentos_4, app.gesto4filter, app.segment_start_gesto_4, app.segment_end_gesto_4]= SegmentV2EEG(app.datosgesto4, app.gesto4Fs, alpha4,app.minimo4.Value, app.window_size4,0);
              
                
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV3023(segmentos_1(i,:), app.gesto1Fs, app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV3023(segmentos_2(i,:), app.gesto2Fs, app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV3023(segmentos_3(i,:), app.gesto3Fs, app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_4,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_4, ventanadatos_gesto_4, ~] = FeaturesV3023(segmentos_4(i,:), app.gesto4Fs, app.canales, size(segmentos_4,2), app.c_gesto_4, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_4 = vector_gesto_4;
                    app.channel_ventana_gesto_4 = ventanadatos_gesto_4;
                    Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4 = [Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4; vector_gesto_4];
                    Features_result.Car_gesto_4.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5,',',seleccion6];
                    Features_result.Car_gesto_4.canales = app.c_gesto_4;
                    Features_result.Car_gesto_4.data = [Features_result.Car_gesto_4.data; ventanadatos_gesto_4];
                    Features_result.Car_gesto_4.FMuestreo = app.gesto4Fs;
                    Features_result.Car_gesto_4.Ventana =  size(segmentos_4,2);
                    Features_result.Car_gesto_4.Etiquetas_gesto_4 = 4*ones(length(Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4),1);
                    % Move to the next segment
                    i = i + 1; 
                end


            elseif selectedButton == app.clases2 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.TiempoContinuoButton
                app.ClassType = 2;
                Features_result.signaltype = 1;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
          
            
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
           elseif selectedButton == app.clases3 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.TiempoContinuoButton
                app.ClassType = 3;
                Features_result.signaltype = 1;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
                % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2EEG(app.datosgesto3, app.gesto2Fs, alpha3,app.minimo3.Value, app.window_size3,0);
           
            
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV2023(segmentos_3(i,:), app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
           elseif selectedButton == app.clases4 && selectedButton2 == app.ElectroencefalografaButton && selectedButton3 == app.TiempoContinuoButton
                app.ClassType = 4;
                Features_result.signaltype = 1;

                %% Segmentation gesto1
                [segmentos_1, app.gesto1filter, app.segment_start_gesto_1, app.segment_end_gesto_1]= SegmentV2EEG(app.datosgesto1, app.gesto1Fs, alpha1,app.minimo1.Value, app.window_size1,0);
                % Segmentation gesto2
                [segmentos_2, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2]= SegmentV2EEG(app.datosgesto2, app.gesto2Fs, alpha2,app.minimo2.Value, app.window_size2,0);
                % Segmentation gesto3
                [segmentos_3, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3]= SegmentV2EEG(app.datosgesto3, app.gesto3Fs, alpha3,app.minimo3.Value, app.window_size3,0);
                 % Segmentation gesto3
                [segmentos_4, app.gesto4filter, app.segment_start_gesto_4, app.segment_end_gesto_4]= SegmentV2EEG(app.datosgesto4, app.gesto4Fs, alpha4,app.minimo3.Value, app.window_size4,0);
           
            
                i = 1;
                while i <= size(segmentos_1,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_1, ventanadatos_gesto_1, ~] = FeaturesV2023(segmentos_1(i,:), app.canales, size(segmentos_1,2), app.c_gesto_1, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_1 = vector_gesto_1;
                    app.channel_ventana_gesto_1 = ventanadatos_gesto_1;
                    Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1 = [Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1; vector_gesto_1];
                    Features_result.Car_gesto_1.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_1.canales = app.c_gesto_1;
                    Features_result.Car_gesto_1.data = [Features_result.Car_gesto_1.data; ventanadatos_gesto_1];
                    Features_result.Car_gesto_1.FMuestreo = app.gesto1Fs;
                    Features_result.Car_gesto_1.Ventana =  size(segmentos_1,2);
                    Features_result.Car_gesto_1.Etiquetas_gesto_1 = ones(length(Features_result.Car_gesto_1.VectorCaracteristicas_gesto_1),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_2,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_2, ventanadatos_gesto_2, ~] = FeaturesV2023(segmentos_2(i,:), app.canales, size(segmentos_2,2), app.c_gesto_2, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_2 = vector_gesto_2;
                    app.channel_ventana_gesto_2 = ventanadatos_gesto_2;
                    Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2 = [Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2; vector_gesto_2];
                    Features_result.Car_gesto_2.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_2.canales = app.c_gesto_2;
                    Features_result.Car_gesto_2.data = [Features_result.Car_gesto_2.data; ventanadatos_gesto_2];
                    Features_result.Car_gesto_2.FMuestreo = app.gesto2Fs;
                    Features_result.Car_gesto_2.Ventana =  size(segmentos_2,2);
                    Features_result.Car_gesto_2.Etiquetas_gesto_2 = 2*ones(length(Features_result.Car_gesto_2.VectorCaracteristicas_gesto_2),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                
                i = 1;
                while i <= size(segmentos_3,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_3, ventanadatos_gesto_3, ~] = FeaturesV2023(segmentos_3(i,:), app.canales, size(segmentos_3,2), app.c_gesto_3, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_3 = vector_gesto_3;
                    app.channel_ventana_gesto_3 = ventanadatos_gesto_3;
                    Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3 = [Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3; vector_gesto_3];
                    Features_result.Car_gesto_3.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_3.canales = app.c_gesto_3;
                    Features_result.Car_gesto_3.data = [Features_result.Car_gesto_3.data; ventanadatos_gesto_3];
                    Features_result.Car_gesto_3.FMuestreo = app.gesto3Fs;
                    Features_result.Car_gesto_3.Ventana =  size(segmentos_3,2);
                    Features_result.Car_gesto_3.Etiquetas_gesto_3 = 3*ones(length(Features_result.Car_gesto_3.VectorCaracteristicas_gesto_3),1);
                    % Move to the next segment
                    i = i + 1; 
                end
                
                i = 1;
                while i <= size(segmentos_4,1)
                    % Extraer características utilizando FeaturesV2 para gesto_1

                    [vector_gesto_4, ventanadatos_gesto_4, ~] = FeaturesV2023(segmentos_4(i,:), app.canales, size(segmentos_4,2), app.c_gesto_4, app.op);
                    
                    % Hacer algo con las características extraídas de gesto_1, por ejemplo, almacenarlas
                    app.Matriz_features_gesto_4 = vector_gesto_4;
                    app.channel_ventana_gesto_4 = ventanadatos_gesto_4;
                    Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4 = [Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4; vector_gesto_4];
                    Features_result.Car_gesto_4.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.Car_gesto_4.canales = app.c_gesto_4;
                    Features_result.Car_gesto_4.data = [Features_result.Car_gesto_4.data; ventanadatos_gesto_4];
                    Features_result.Car_gesto_4.FMuestreo = app.gesto4Fs;
                    Features_result.Car_gesto_4.Ventana =  size(segmentos_4,2);
                    Features_result.Car_gesto_4.Etiquetas_gesto_4 = 4*ones(length(Features_result.Car_gesto_4.VectorCaracteristicas_gesto_4),1);
                    % Move to the next segment
                    i = i + 1; 
                end     


            end
                
                
            Features_result.Tipo_Clasificacion = app.ClassType;
            Features_result.Vector_Caracteristicas = app.op;
            Features_result.Tipo_Caracteristicas = 0;
        
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('VectorFeatures.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Features_result')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
            
        end

        % Selection changed function: CaractersticasButtonGroup
        function CaractersticasButtonGroupSelectionChanged(app, event)
            selectedButton = app.CaractersticasButtonGroup.SelectedObject;
            if selectedButton == app.TiempoContinuoButton
                app.TiempoContinuoPanel.Enable = 'on';
                app.FrecuenciaPanel.Enable = 'off';

            else 
                app.TiempoContinuoPanel.Enable = 'off';
                app.FrecuenciaPanel.Enable = 'on';
            end
        end

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
            
            [file,path,~] = uigetfile({'*.mat'}, 'Selecciona el .mat del gesto');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable
                %con 'gesto'
                workspace = load(strcat(path,file),'gesto');
                %Cargamos la señal a la variable de datos respectiva
                app.gesto_segmentar = workspace.gesto;
                app.datosgesto = app.gesto_segmentar.data
                app.gestoFs = app.gesto_segmentar.sampling_frequency;
                
            end
        end

        % Button pushed function: extraergesto1
        function extraergesto1Pushed(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
           
           if selectedButton == app.ElectromiografaButton
                    % Call the segment_auto_func function with the data as arguments
                    [~] = segment_auto_funcV2(app.gesto_segmentar_1, app.Alpha1Spinner.Value,app.minimo1.Value, app.window_size1);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
                    
                elseif selectedButton == app.ElectroencefalografaButton
                    [~] = segment_auto_funcV2EEG(app.gesto_segmentar_1, app.Alpha1Spinner.Value,app.minimo1.Value, app.window_size1);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
           end
        end

        % Button pushed function: extraergesto2
        function extraergesto2Pushed(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
           
           if selectedButton == app.ElectromiografaButton
                    % Call the segment_auto_func function with the data as arguments
                    [~] = segment_auto_funcV2(app.gesto_segmentar_2, app.Alpha2Spinner.Value,app.minimo2.Value, app.window_size2);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
                    
                elseif selectedButton == app.ElectroencefalografaButton
                    [~] = segment_auto_funcV2EEG(app.gesto_segmentar_2, app.Alpha2Spinner.Value,app.minimo2.Value, app.window_size2);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
           end
        end

        % Button pushed function: extraergesto3
        function extraergesto3Pushed(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
           
           if selectedButton == app.ElectromiografaButton
                    % Call the segment_auto_func function with the data as arguments
                    [~] = segment_auto_funcV2(app.gesto_segmentar_3, app.Alpha3Spinner.Value,app.minimo3.Value, app.window_size3);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
                    
                elseif selectedButton == app.ElectroencefalografaButton
                    [~] = segment_auto_funcV2EEG(app.gesto_segmentar_3, app.Alpha3Spinner.Value,app.minimo3.Value, app.window_size3);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
           end
        end

        % Button pushed function: extraergesto4
        function extraergesto4Pushed(app, event)
            selectedButton = app.TipodeanlisisButtonGroup.SelectedObject;
           
           if selectedButton == app.ElectromiografaButton
                    % Call the segment_auto_func function with the data as arguments
                    [~] = segment_auto_funcV2(app.gesto_segmentar_4, app.Alpha4Spinner.Value,app.minimo4.Value, app.window_size4);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
                    
                elseif selectedButton == app.ElectroencefalografaButton
                    [~] = segment_auto_funcV2EEG(app.gesto_segmentar_4, app.Alpha4Spinner.Value,app.minimo4.Value, app.window_size4);
                    %Mensaje de exito 
                    message = sprintf(['Archivo ',' guardado exitosamente']);
                    uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');
           end
        end

        % Button pushed function: SegmentarButton_2
        function SegmentarButton_2Pushed(app, event)
            % Variables de segmentacion
            alpha = app.KSpinner.Value;
            window_size = app.VentanaSpinner.Value;
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;
            
            if selectedButton2 == app.ElectromiografaButton
                    [~, app.gestofilter, ~, ~] = SegmentV2(app.datosgesto, app.gestoFs,alpha,app.minimo.Value,window_size, 1);
                    
             elseif selectedButton2 == app.ElectroencefalografaButton
                    [~, app.gestofilter, ~, ~] = SegmentV2EEG(app.datosgesto, app.gestoFs,alpha,app.minimo.Value,window_size, 1);
            end
             
            if app.selectedButton== app.clases2 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif app.selectedButton== app.clases3 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif app.selectedButton== app.clases4 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on') && strcmp(app.extraergesto4.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            end
        end

        % Button pushed function: Segmentar3Button
        function Segmentar3ButtonPushed(app, event)
            
            alpha3 = app.Alpha3Spinner.Value;
            % Assume the user inputs the window size in seconds
            window_size_seconds = app.Ventana3Spinner.Value;
            % Convert window size to samples based on the sampling frequency (Fs)
            app.window_size3 = round(window_size_seconds * app.gesto3Fs); 
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;
            
            if selectedButton2 == app.ElectromiografaButton
                %% Segmentation for gesto3
                [~, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3] = SegmentV2(app.datosgesto3, app.gesto3Fs,alpha3,app.minimo3.Value,app.window_size3, 1);
                app.extraergesto3.Enable = 'on';
                
            elseif selectedButton2 == app.ElectroencefalografaButton
                %% Segmentation for gesto3
                [~, app.gesto3filter, app.segment_start_gesto_3, app.segment_end_gesto_3] = SegmentV2EEG(app.datosgesto3, app.gesto3Fs,alpha3,app.minimo3.Value,app.window_size3, 1);
                app.extraergesto3.Enable = 'on';     
            end
            app.segmentosgesto3.Value = length(app.segment_start_gesto_3);
 
            selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;

            if selectedButton== app.clases2 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases3 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases4 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on') && strcmp(app.extraergesto4.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';

            end

        end

        % Button pushed function: Segmentar2Button
        function Segmentar2ButtonPushed(app, event)
            alpha2 = app.Alpha2Spinner.Value;
            % Assume the user inputs the window size in seconds
            window_size_seconds = app.Ventana2Spinner.Value;
            % Convert window size to samples based on the sampling frequency (Fs)
            app.window_size2 = round(window_size_seconds * app.gesto2Fs);
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;

            if selectedButton2 == app.ElectromiografaButton
                %% Segmentation for gesto2
                [~, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2] = SegmentV2(app.datosgesto2, app.gesto2Fs,alpha2,app.minimo2.Value,app.window_size2, 1);
                app.extraergesto2.Enable = 'on';
            
           elseif selectedButton2 == app.ElectroencefalografaButton
                %% Segmentation for gesto2
                [~, app.gesto2filter, app.segment_start_gesto_2, app.segment_end_gesto_2] = SegmentV2EEG(app.datosgesto2, app.gesto2Fs,alpha2,app.minimo2.Value,app.window_size2, 1);
                app.extraergesto2.Enable = 'on';           
            end
            
            
           app.segmentosgesto2.Value = length(app.segment_start_gesto_2);
           selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;

            if selectedButton== app.clases2 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases3 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases4 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on') && strcmp(app.extraergesto4.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            end

        end

        % Button pushed function: Segmentar4Button
        function Segmentar4ButtonPushed(app, event)
            % Variables de segmentacion
            
            alpha4 = app.Alpha4Spinner.Value;
            window_size_seconds = app.Ventana4Spinner.Value;
            % Convert window size to samples based on the sampling frequency (Fs)
            app.window_size4 = round(window_size_seconds * app.gesto4Fs);             
            selectedButton2 = app.TipodeanlisisButtonGroup.SelectedObject;
            
            
            if selectedButton2 == app.ElectromiografaButton
                 %% Segmentation for gesto4
                [~, app.gesto4filter, app.segment_start_gesto_4, app.segment_end_gesto_4] = SegmentV2(app.datosgesto4, app.gesto4Fs,alpha4,app.minimo4.Value,app.window_size4, 1);
                app.extraergesto4.Enable = 'on';
                
            elseif selectedButton2 == app.ElectroencefalografaButton
                %% Segmentation for gesto4
                [~, app.gesto4filter, app.segment_start_gesto_4, app.segment_end_gesto_4] = SegmentV2EEG(app.datosgesto4, app.gesto4Fs,alpha4,app.minimo4.Value,app.window_size4, 1);
                app.extraergesto4.Enable = 'on';           
            end
            
        app.segmentosgesto4.Value = length(app.segment_start_gesto_4);
        selectedButton = app.CantidaddeclasesButtonGroup.SelectedObject;

            if selectedButton== app.clases2 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases3 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';
            elseif selectedButton== app.clases4 && strcmp(app.extraergesto1.Enable, 'on') && strcmp(app.extraergesto2.Enable, 'on') && strcmp(app.extraergesto3.Enable, 'on') && strcmp(app.extraergesto4.Enable, 'on')
                app.ExtraerMatrizdeCaractersticasButton.Enable = 'on';           
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {698, 698, 698};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {698, 698};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {465, '1x', 272};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup6 = uitabgroup(app.LeftPanel);
            app.GestosTab = uitab(app.TabGroup6);
            app.GestosTab.Title = 'Gestos';
            app.Gesto1Panel = uipanel(app.GestosTab);
            app.Gesto1Panel.Title = 'Gesto 1';
            app.gesto1button = uibutton(app.Gesto1Panel, 'push');
            app.gesto1button.ButtonPushedFcn = createCallbackFcn(app, @gesto1buttonButtonPushed, true);
            app.gesto1button.Text = 'Cargar .mat';
            app.gesto1size = uilabel(app.Gesto1Panel);
            app.gesto1size.Text = 'Tamaño: -';
            app.gesto1time = uilabel(app.Gesto1Panel);
            app.gesto1time.Text = 'Duración: -';
            app.gesto1fs = uilabel(app.Gesto1Panel);
            app.gesto1fs.Text = 'Freq. Muestreo (Fs):';
            app.Gesto2Panel = uipanel(app.GestosTab);
            app.Gesto2Panel.Title = 'Gesto 2';
            app.gesto2button = uibutton(app.Gesto2Panel, 'push');
            app.gesto2button.ButtonPushedFcn = createCallbackFcn(app, @gesto2buttonButtonPushed, true);
            app.gesto2button.Text = 'Cargar .mat';
            app.gesto2size = uilabel(app.Gesto2Panel);
            app.gesto2size.Text = 'Tamaño: -';
            app.gesto2time = uilabel(app.Gesto2Panel);
            app.gesto2time.Text = 'Duración: -';
            app.gesto2fs = uilabel(app.Gesto2Panel);
            app.gesto2fs.Text = 'Freq. Muestreo (Fs):';
            app.Gesto3Panel = uipanel(app.GestosTab);
            app.Gesto3Panel.Enable = 'off';
            app.Gesto3Panel.Title = 'Gesto 3';
            app.Gesto3Panel.Visible = 'off';
            app.gesto3button = uibutton(app.Gesto3Panel, 'push');
            app.gesto3button.ButtonPushedFcn = createCallbackFcn(app, @gesto3buttonButtonPushed, true);
            app.gesto3button.Text = 'Cargar .mat';
            app.gesto3size = uilabel(app.Gesto3Panel);
            app.gesto3size.Text = 'Tamaño: -';
            app.gesto3time = uilabel(app.Gesto3Panel);
            app.gesto3time.Text = 'Duración: -';
            app.gesto3fs = uilabel(app.Gesto3Panel);
            app.gesto3fs.Text = 'Freq. Muestreo (Fs):';
            app.Gesto4Panel = uipanel(app.GestosTab);
            app.Gesto4Panel.Enable = 'off';
            app.Gesto4Panel.Title = 'Gesto 4';
            app.Gesto4Panel.Visible = 'off';
            app.gesto4button = uibutton(app.Gesto4Panel, 'push');
            app.gesto4button.ButtonPushedFcn = createCallbackFcn(app, @gesto4buttonButtonPushed, true);
            app.gesto4button.Text = 'Cargar .mat';
            app.gesto4size = uilabel(app.Gesto4Panel);
            app.gesto4size.Text = 'Tamaño: -';
            app.gesto4time = uilabel(app.Gesto4Panel);
            app.gesto4time.Text = 'Duración: -';
            app.gesto4fs = uilabel(app.Gesto4Panel);
            app.gesto4fs.Text = 'Freq. Muestreo (Fs):';
            app.TipodeanlisisButtonGroup = uibuttongroup(app.GestosTab);
            app.TipodeanlisisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeanlisisButtonGroupSelectionChanged, true);
            app.TipodeanlisisButtonGroup.Title = 'Tipo de análisis';
            app.ElectromiografaButton = uiradiobutton(app.TipodeanlisisButtonGroup);
            app.ElectromiografaButton.Text = 'Electromiografía';
            app.ElectromiografaButton.Value = true;
            app.ElectroencefalografaButton = uiradiobutton(app.TipodeanlisisButtonGroup);
            app.ElectroencefalografaButton.Text = 'Electroencefalografía';
            app.CantidaddeclasesButtonGroup = uibuttongroup(app.GestosTab);
            app.CantidaddeclasesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CantidaddeclasesButtonGroupSelectionChanged, true);
            app.CantidaddeclasesButtonGroup.Title = 'Cantidad de clases';
            app.clases2 = uiradiobutton(app.CantidaddeclasesButtonGroup);
            app.clases2.Text = '2 clases';
            app.clases2.Value = true;
            app.clases3 = uiradiobutton(app.CantidaddeclasesButtonGroup);
            app.clases3.Text = '3 clases';
            app.clases4 = uiradiobutton(app.CantidaddeclasesButtonGroup);
            app.clases4.Text = '4 clases';
            app.CapturarBiopacTab = uitab(app.TabGroup6);
            app.CapturarBiopacTab.Title = 'Capturar Biopac';
            app.CapturarconBiopacygenerarxlsxPanel = uipanel(app.CapturarBiopacTab);
            app.CapturarconBiopacygenerarxlsxPanel.Title = 'Capturar con Biopac y generar .xlsx';
            app.IniciarBSLAnalysisButton = uibutton(app.CapturarconBiopacygenerarxlsxPanel, 'push');
            app.IniciarBSLAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarBSLAnalysisButtonPushed, true);
            app.IniciarBSLAnalysisButton.Text = 'Iniciar BSL Analysis';
            app.CrearPlantillaxlsxButton = uibutton(app.CapturarconBiopacygenerarxlsxPanel, 'push');
            app.CrearPlantillaxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @CrearPlantillaxlsxButtonPushed, true);
            app.CrearPlantillaxlsxButton.Text = 'Crear Plantilla.xlsx';
            app.SeleccionarplantillasxlsxyextraerPanel = uipanel(app.CapturarBiopacTab);
            app.SeleccionarplantillasxlsxyextraerPanel.Title = 'Seleccionar plantillas .xlsx y extraer';
            app.ExtraermatsButton = uibutton(app.SeleccionarplantillasxlsxyextraerPanel, 'push');
            app.ExtraermatsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraermatsButtonPushed, true);
            app.ExtraermatsButton.Text = 'Extraer .mat(s)';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.CenterPanel);
            app.Gesto1Tab = uitab(app.TabGroup3);
            app.Gesto1Tab.Title = 'Gesto 1';
            app.gesto1fig = uiaxes(app.Gesto1Tab);
            xlabel(app.gesto1fig, 't (s)')
            ylabel(app.gesto1fig, 'V')
            app.SegmentosdeactividaddetectadosEditFieldLabel = uilabel(app.Gesto1Tab);
            app.SegmentosdeactividaddetectadosEditFieldLabel.Text = 'Segmentos de actividad detectados';
            app.segmentosgesto1 = uieditfield(app.Gesto1Tab, 'numeric');
            app.Gesto2Tab = uitab(app.TabGroup3);
            app.Gesto2Tab.Title = 'Gesto 2';
            app.gesto2fig = uiaxes(app.Gesto2Tab);
            xlabel(app.gesto2fig, 't (s)')
            ylabel(app.gesto2fig, 'V')
            app.SegmentosdeactividaddetectadosEditField_2Label = uilabel(app.Gesto2Tab);
            app.SegmentosdeactividaddetectadosEditField_2Label.Text = 'Segmentos de actividad detectados';
            app.segmentosgesto2 = uieditfield(app.Gesto2Tab, 'numeric');
            app.Gesto3Tab = uitab(app.TabGroup3);
            app.Gesto3Tab.Title = 'Gesto 3';
            app.gesto3fig = uiaxes(app.Gesto3Tab);
            xlabel(app.gesto3fig, 't (s)')
            ylabel(app.gesto3fig, 'V')
            app.SegmentosdeactividaddetectadosEditField_3Label = uilabel(app.Gesto3Tab);
            app.SegmentosdeactividaddetectadosEditField_3Label.Text = 'Segmentos de actividad detectados';
            app.segmentosgesto3 = uieditfield(app.Gesto3Tab, 'numeric');
            app.Gesto4Tab = uitab(app.TabGroup3);
            app.Gesto4Tab.Title = 'Gesto 4';
            app.gesto4fig = uiaxes(app.Gesto4Tab);
            xlabel(app.gesto4fig, 't (s)')
            ylabel(app.gesto4fig, 'V')
            app.SegmentosdeactividaddetectadosEditField_4Label = uilabel(app.Gesto4Tab);
            app.SegmentosdeactividaddetectadosEditField_4Label.Text = 'Segmentos de actividad detectados';
            app.segmentosgesto4 = uieditfield(app.Gesto4Tab, 'numeric');
            app.TabGroup7 = uitabgroup(app.CenterPanel);
            app.SegmentacionTab = uitab(app.TabGroup7);
            app.SegmentacionTab.Title = 'Segmentacion';
            app.Gesto1Segmentacion = uipanel(app.SegmentacionTab);
            app.Gesto1Segmentacion.Title = 'Gesto 1';
            app.KLabel = uilabel(app.Gesto1Segmentacion);
            app.KLabel.Text = 'K';
            app.Alpha1Spinner = uispinner(app.Gesto1Segmentacion);
            app.Alpha1Spinner.Step = 0.001;
            app.Alpha1Spinner.Limits = [0 0.1];
            app.Alpha1Spinner.Value = 0.001;
            app.VentanaSpinnerLabel = uilabel(app.Gesto1Segmentacion);
            app.VentanaSpinnerLabel.Text = 'Ventana';
            app.Ventana1Spinner = uispinner(app.Gesto1Segmentacion);
            app.Ventana1Spinner.Step = 0.1;
            app.Ventana1Spinner.Limits = [0 6000];
            app.Ventana1Spinner.Value = 0.5;
            app.extraergesto1 = uibutton(app.Gesto1Segmentacion, 'push');
            app.extraergesto1.ButtonPushedFcn = createCallbackFcn(app, @extraergesto1Pushed, true);
            app.extraergesto1.Enable = 'off';
            app.extraergesto1.Text = 'Extraer Segmentos';
            app.LEditFieldLabel = uilabel(app.Gesto1Segmentacion);
            app.LEditFieldLabel.Visible = 'off';
            app.LEditFieldLabel.Text = 'L';
            app.minimo1 = uieditfield(app.Gesto1Segmentacion, 'numeric');
            app.minimo1.Visible = 'off';
            app.minimo1.Value = 300;
            app.Segmentar1Button = uibutton(app.Gesto1Segmentacion, 'push');
            app.Segmentar1Button.ButtonPushedFcn = createCallbackFcn(app, @Segmentar1ButtonPushed, true);
            app.Segmentar1Button.Text = 'Segmentar';
            app.sLabel = uilabel(app.Gesto1Segmentacion);
            app.sLabel.Text = 's';
            app.Gesto2Segmentacion = uipanel(app.SegmentacionTab);
            app.Gesto2Segmentacion.Title = 'Gesto 2';
            app.KLabel_2 = uilabel(app.Gesto2Segmentacion);
            app.KLabel_2.Text = 'K';
            app.Alpha2Spinner = uispinner(app.Gesto2Segmentacion);
            app.Alpha2Spinner.Step = 0.001;
            app.Alpha2Spinner.Limits = [0 0.1];
            app.Alpha2Spinner.Value = 0.001;
            app.VentanaSpinnerLabel_2 = uilabel(app.Gesto2Segmentacion);
            app.VentanaSpinnerLabel_2.Text = 'Ventana';
            app.Ventana2Spinner = uispinner(app.Gesto2Segmentacion);
            app.Ventana2Spinner.Step = 0.1;
            app.Ventana2Spinner.Limits = [0 6000];
            app.Ventana2Spinner.Value = 0.5;
            app.extraergesto2 = uibutton(app.Gesto2Segmentacion, 'push');
            app.extraergesto2.ButtonPushedFcn = createCallbackFcn(app, @extraergesto2Pushed, true);
            app.extraergesto2.Enable = 'off';
            app.extraergesto2.Text = 'Extraer Segmentos';
            app.LEditField_2Label = uilabel(app.Gesto2Segmentacion);
            app.LEditField_2Label.Visible = 'off';
            app.LEditField_2Label.Text = 'L';
            app.minimo2 = uieditfield(app.Gesto2Segmentacion, 'numeric');
            app.minimo2.Visible = 'off';
            app.minimo2.Value = 150;
            app.Segmentar2Button = uibutton(app.Gesto2Segmentacion, 'push');
            app.Segmentar2Button.ButtonPushedFcn = createCallbackFcn(app, @Segmentar2ButtonPushed, true);
            app.Segmentar2Button.Text = 'Segmentar';
            app.sLabel_2 = uilabel(app.Gesto2Segmentacion);
            app.sLabel_2.Text = 's';
            app.Gesto3Segmentacion = uipanel(app.SegmentacionTab);
            app.Gesto3Segmentacion.Enable = 'off';
            app.Gesto3Segmentacion.Title = 'Gesto 3';
            app.Gesto3Segmentacion.Visible = 'off';
            app.KLabel_3 = uilabel(app.Gesto3Segmentacion);
            app.KLabel_3.Text = 'K';
            app.Alpha3Spinner = uispinner(app.Gesto3Segmentacion);
            app.Alpha3Spinner.Step = 0.001;
            app.Alpha3Spinner.Limits = [0 0.1];
            app.Alpha3Spinner.Value = 0.001;
            app.VentanaSpinnerLabel_3 = uilabel(app.Gesto3Segmentacion);
            app.VentanaSpinnerLabel_3.Text = 'Ventana';
            app.Ventana3Spinner = uispinner(app.Gesto3Segmentacion);
            app.Ventana3Spinner.Step = 0.1;
            app.Ventana3Spinner.Limits = [0 6000];
            app.Ventana3Spinner.Value = 0.5;
            app.extraergesto3 = uibutton(app.Gesto3Segmentacion, 'push');
            app.extraergesto3.ButtonPushedFcn = createCallbackFcn(app, @extraergesto3Pushed, true);
            app.extraergesto3.Enable = 'off';
            app.extraergesto3.Text = 'Extraer Segmentos';
            app.LEditField_4Label = uilabel(app.Gesto3Segmentacion);
            app.LEditField_4Label.Visible = 'off';
            app.LEditField_4Label.Text = 'L';
            app.minimo3 = uieditfield(app.Gesto3Segmentacion, 'numeric');
            app.minimo3.Visible = 'off';
            app.minimo3.Value = 150;
            app.Segmentar3Button = uibutton(app.Gesto3Segmentacion, 'push');
            app.Segmentar3Button.ButtonPushedFcn = createCallbackFcn(app, @Segmentar3ButtonPushed, true);
            app.Segmentar3Button.Text = 'Segmentar';
            app.sLabel_3 = uilabel(app.Gesto3Segmentacion);
            app.sLabel_3.Text = 's';
            app.Gesto4Segmentacion = uipanel(app.SegmentacionTab);
            app.Gesto4Segmentacion.Enable = 'off';
            app.Gesto4Segmentacion.Title = 'Gesto 4';
            app.Gesto4Segmentacion.Visible = 'off';
            app.KLabel_4 = uilabel(app.Gesto4Segmentacion);
            app.KLabel_4.Text = 'K';
            app.Alpha4Spinner = uispinner(app.Gesto4Segmentacion);
            app.Alpha4Spinner.Step = 0.001;
            app.Alpha4Spinner.Limits = [0 0.1];
            app.Alpha4Spinner.Value = 0.001;
            app.VentanaSpinnerLabel_4 = uilabel(app.Gesto4Segmentacion);
            app.VentanaSpinnerLabel_4.Text = 'Ventana';
            app.Ventana4Spinner = uispinner(app.Gesto4Segmentacion);
            app.Ventana4Spinner.Step = 0.1;
            app.Ventana4Spinner.Limits = [0 6000];
            app.Ventana4Spinner.Value = 0.5;
            app.extraergesto4 = uibutton(app.Gesto4Segmentacion, 'push');
            app.extraergesto4.ButtonPushedFcn = createCallbackFcn(app, @extraergesto4Pushed, true);
            app.extraergesto4.Enable = 'off';
            app.extraergesto4.Text = 'Extraer Segmentos';
            app.LEditField_3Label = uilabel(app.Gesto4Segmentacion);
            app.LEditField_3Label.Visible = 'off';
            app.LEditField_3Label.Text = 'L';
            app.minimo4 = uieditfield(app.Gesto4Segmentacion, 'numeric');
            app.minimo4.Visible = 'off';
            app.minimo4.Value = 150;
            app.Segmentar4Button = uibutton(app.Gesto4Segmentacion, 'push');
            app.Segmentar4Button.ButtonPushedFcn = createCallbackFcn(app, @Segmentar4ButtonPushed, true);
            app.Segmentar4Button.Text = 'Segmentar';
            app.sLabel_4 = uilabel(app.Gesto4Segmentacion);
            app.sLabel_4.Text = 's';
            app.SegmentarmatexternoTab = uitab(app.TabGroup7);
            app.SegmentarmatexternoTab.Title = 'Segmentar .mat externo';
            app.GestoSegmentacion = uipanel(app.SegmentarmatexternoTab);
            app.GestoSegmentacion.Title = 'Gesto ';
            app.KSpinnerLabel = uilabel(app.GestoSegmentacion);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.GestoSegmentacion);
            app.KSpinner.Step = 0.001;
            app.KSpinner.Limits = [0 0.1];
            app.KSpinner.Value = 0.001;
            app.VentanaSpinnerLabel_5 = uilabel(app.GestoSegmentacion);
            app.VentanaSpinnerLabel_5.Text = 'Ventana';
            app.VentanaSpinner = uispinner(app.GestoSegmentacion);
            app.VentanaSpinner.Step = 10;
            app.VentanaSpinner.Limits = [0 500];
            app.VentanaSpinner.Value = 100;
            app.CargarmatButton = uibutton(app.GestoSegmentacion, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar .mat';
            app.LEditField_2Label_2 = uilabel(app.GestoSegmentacion);
            app.LEditField_2Label_2.Text = 'L';
            app.minimo = uieditfield(app.GestoSegmentacion, 'numeric');
            app.minimo.Value = 150;
            app.ExtraersegmentosButton = uibutton(app.SegmentarmatexternoTab, 'push');
            app.ExtraersegmentosButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraersegmentosButtonPushed, true);
            app.ExtraersegmentosButton.Text = 'Extraer segmentos ';
            app.SegmentarButton_2 = uibutton(app.SegmentarmatexternoTab, 'push');
            app.SegmentarButton_2.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButton_2Pushed, true);
            app.SegmentarButton_2.Text = 'Segmentar';
            app.RightPanel = uipanel(app.GridLayout);
            app.TiempoContinuoPanel = uipanel(app.RightPanel);
            app.TiempoContinuoPanel.Title = 'Tiempo Continuo';
            app.SeleccionartodoCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.SeleccionartodoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBoxValueChanged, true);
            app.SeleccionartodoCheckBox.Text = 'Seleccionar todo';
            app.TCCrucesporceroZCCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.TCCrucesporceroZCCheckBox.Text = 'Zero Crossing (ZC)';
            app.TCValorMedioAbsolutoMAVCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.TCValorMedioAbsolutoMAVCheckBox.Text = 'Valor Medio Absoluto MAV';
            app.TCCurtosisCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.TCCurtosisCheckBox.Text = 'Curtosis';
            app.TCEnergaAcumuladaCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.TCEnergaAcumuladaCheckBox.Text = 'Energía Acumulada';
            app.TCDesviacinEstndarCheckBox = uicheckbox(app.TiempoContinuoPanel);
            app.TCDesviacinEstndarCheckBox.Text = 'Desviación Estándar';
            app.FrecuenciaPanel = uipanel(app.RightPanel);
            app.FrecuenciaPanel.Enable = 'off';
            app.FrecuenciaPanel.Title = 'Frecuencia';
            app.FrecuenciaPanel.Visible = 'off';
            app.ratio_4CheckBox = uicheckbox(app.FrecuenciaPanel);
            app.ratio_4CheckBox.Text = 'θ/α';
            app.ratio_2CheckBox = uicheckbox(app.FrecuenciaPanel);
            app.ratio_2CheckBox.Text = 'β/α';
            app.ratio_1CheckBox = uicheckbox(app.FrecuenciaPanel);
            app.ratio_1CheckBox.Text = 'θ/β';
            app.ratio_3CheckBox = uicheckbox(app.FrecuenciaPanel);
            app.ratio_3CheckBox.Text = '(θ + α)/β';
            app.ratio_5CheckBox = uicheckbox(app.FrecuenciaPanel);
            app.ratio_5CheckBox.Text = '(θ + α)/(α + β)';
            app.StdFreqCheckBox = uicheckbox(app.FrecuenciaPanel);
            app.StdFreqCheckBox.Text = 'Desviación Estándar';
            app.SeleccionartodoCheckBox_2 = uicheckbox(app.FrecuenciaPanel);
            app.SeleccionartodoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SeleccionartodoCheckBox_2ValueChanged, true);
            app.SeleccionartodoCheckBox_2.Text = 'Seleccionar todo';
            app.ExtraerMatrizdeCaractersticasButton = uibutton(app.RightPanel, 'push');
            app.ExtraerMatrizdeCaractersticasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerMatrizdeCaractersticasButtonPushed, true);
            app.ExtraerMatrizdeCaractersticasButton.Enable = 'off';
            app.ExtraerMatrizdeCaractersticasButton.Text = 'Extraer Matriz de Características';
            app.CaractersticasButtonGroup = uibuttongroup(app.RightPanel);
            app.CaractersticasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaractersticasButtonGroupSelectionChanged, true);
            app.CaractersticasButtonGroup.Enable = 'off';
            app.CaractersticasButtonGroup.Title = 'Características';
            app.CaractersticasButtonGroup.Visible = 'off';
            app.TiempoContinuoButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.TiempoContinuoButton.Text = 'Tiempo Continuo';
            app.TiempoContinuoButton.Value = true;
            app.FrecuenciaButton = uiradiobutton(app.CaractersticasButtonGroup);
            app.FrecuenciaButton.Text = 'Frecuencia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recoleccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end