classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        SealesenTiempoRealBitalinoTab  matlab.ui.container.Tab
        SealesentiempoRealPanel        matlab.ui.container.Panel
        SinActividadLabel              matlab.ui.control.Label
        ClaseLabel                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EnpausaLabel                   matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        MediaCuadraticaEditField       matlab.ui.control.NumericEditField
        MediaCuadraticaEditFieldLabel  matlab.ui.control.Label
        WilsonAmplitudEditField        matlab.ui.control.NumericEditField
        WilsonAmplitudEditFieldLabel   matlab.ui.control.Label
        VarianzaEditField              matlab.ui.control.NumericEditField
        VarianzaEditFieldLabel         matlab.ui.control.Label
        AverageEnergyEditField         matlab.ui.control.NumericEditField
        AverageEnergyEditFieldLabel    matlab.ui.control.Label
        KurtossisEditField             matlab.ui.control.NumericEditField
        KurtossisEditFieldLabel        matlab.ui.control.Label
        MAVEditField                   matlab.ui.control.NumericEditField
        MAVEditFieldLabel              matlab.ui.control.Label
        ZeroCrossingEditField          matlab.ui.control.NumericEditField
        ZeroCrossingEditFieldLabel     matlab.ui.control.Label
        Canal1Label                    matlab.ui.control.Label
        figFpz_2                       matlab.ui.control.UIAxes
        RobotBiopacTab                 matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        VisualizacinLabel              matlab.ui.control.Label
        EEGDropDown                    matlab.ui.control.DropDown
        EEGDropDownLabel               matlab.ui.control.Label
        SimulacinLabel                 matlab.ui.control.Label
        CargarEntrenamientoButton_2    matlab.ui.control.Button
        SealEEGLabel                   matlab.ui.control.Label
        CargarmatButton_3              matlab.ui.control.Button
        SealEMGLabel                   matlab.ui.control.Label
        IniciarButton_2                matlab.ui.control.Button
        CargarEntrenamientoButton      matlab.ui.control.Button
        CargarmatButton_4              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        BitalinoBluetoothTab           matlab.ui.container.Tab
        PaneldeControl                 matlab.ui.container.Panel
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        EscogerEntrenamientoButton     matlab.ui.control.Button
        PararButton                    matlab.ui.control.Button
        NombreBluetoothBitalinoEditField  matlab.ui.control.EditField
        NombreBluetoothBitalinoEditFieldLabel  matlab.ui.control.Label
        EmpezarStreamingSwitch         matlab.ui.control.Switch
        EmpezarStreamingSwitchLabel    matlab.ui.control.Label
        ConectarBitalinoSwitch         matlab.ui.control.Switch
        ConectarBitalinoSwitchLabel    matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        ThSpinner                      matlab.ui.control.Spinner
        ThSpinnerLabel                 matlab.ui.control.Label
        TiempodemuestraEditField       matlab.ui.control.NumericEditField
        TiempodemuestraEditFieldLabel  matlab.ui.control.Label
        IniciarButton                  matlab.ui.control.Button
        ButtonsPanel                   matlab.ui.container.Panel
        LampRed                        matlab.ui.control.Lamp
        LampGreen                      matlab.ui.control.Lamp
        STOPButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        COMDropDown                    matlab.ui.control.DropDown
        COMDropDownLabel               matlab.ui.control.Label
        SPEEDEditField                 matlab.ui.control.NumericEditField
        SPEEDEditFieldLabel            matlab.ui.control.Label
        BiopacTab                      matlab.ui.container.Tab
        LearningRateEditField          matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel     matlab.ui.control.Label
        EpochsEditField                matlab.ui.control.NumericEditField
        EpochsEditFieldLabel           matlab.ui.control.Label
        SaveAsButton                   matlab.ui.control.Button
        ExactituddelclasificadorLabel  matlab.ui.control.Label
        EntrenarButton                 matlab.ui.control.Button
        CargarmatButton                matlab.ui.control.Button
        KernelaemplearDropDown         matlab.ui.control.DropDown
        KernelDropDownLabel            matlab.ui.control.Label
        NmerodeParticionesKSpinner     matlab.ui.control.Spinner
        KfoldsLabel                    matlab.ui.control.Label
        ValidacinCruzadaLabel          matlab.ui.control.Label
        MaquinaSoporteVectorialSVMLabel  matlab.ui.control.Label
        CapasOcultasDropDown           matlab.ui.control.DropDown
        CapasOcultasDropDownLabel      matlab.ui.control.Label
        AlgoritmoparaelentrenamientoDropDown  matlab.ui.control.DropDown
        AlgoritmoRNADropDownLabel      matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PruebaEditFieldLabel           matlab.ui.control.Label
        SegmentosPrueba                matlab.ui.control.NumericEditField
        ValidacionEditFieldLabel       matlab.ui.control.Label
        SegmentosValidacion            matlab.ui.control.NumericEditField
        SegmentosEntrenamiento         matlab.ui.control.NumericEditField
        EntrenamientoEditFieldLabel    matlab.ui.control.Label
        ClasificadorRedNeuronalLabel   matlab.ui.control.Label
        SeleccionarclasificadorButtonGroup  matlab.ui.container.ButtonGroup
        SVMButton                      matlab.ui.control.ToggleButton
        RedNeuronalButton              matlab.ui.control.ToggleButton
        VectorCaractersticasEMGEEGLabel  matlab.ui.control.Label
        Biopac_Image                   matlab.ui.control.Image
        BCIEMGEEGUVGLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        R17 = 0;
        COM = 0;
        Pausa = 0;
        loadedData = 0;
        SPEED = 0;
        flag_recibir = 0;
        Fs = 44100;
        Onda_Low = 7;
        Onda_High = 14;
        typeofdatastore;
        y1 = audioread('Click.wav');
        Bit=0;
        F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda
        F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda
        data_n=0;
        BanderaParaEmpezar=0;
        Estructura_Caracteristicas = 0;
        NombreBit=0;
        flag_recibir2 =0;
        T = table()
        Array_giro =[45,90,135,180,135,90,45,0,-45,-90,-135,-180,-135,-90,-45,0];
        Metros_CAREER = [0.2,0.4,0.6,0.4,0.2,0,-0.2,-0.4,-0.2,0]
        flag_recibir1=0;
        A=0;
        AlgorimoAprendizajeA;
        flag_terminar=0;
        NumberOfEpochs = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            app.Lamp.Enable='off';
            app.STOPButton.Enable = 'off';
            app.T.HjorthActivity = zeros(0);
            app.T.NormalizedFirstDifference = zeros(0);
            app.T.HjorthComplexity = zeros(0);
            app.T.Kurtosis = zeros(0);
            app.T.MeanEnergy = zeros(0);
            app.T.Skewness = zeros(0);
            app.T.ShannonEntropy = zeros(0);
            app.T.HjorthMobility = zeros(0);
            app.T.PCALatent = zeros(0);
            app.T.PCAMu = zeros(0);
            app.T.Clase = zeros(0);
        end

        % Callback function
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
            delete(app)
        end

        % Callback function
        function ActivarStreamingSwitchValueChanged(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)
            global R17
            q1 = [0; 0;90; 0; -45; 0];
            R17fKine(R17, q1);
        end

        % Callback function
        function RegresarButtonPushed2(app, event)
            clear all
            Interfaz_Inicio
            delete(app)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            soundsc(app.y1,app.Fs);
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable ='on';
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.EnpausaLabel.Enable='off';
            app.SPEED = app.SPEEDEditField.Value;
            %app.R17 = R17Init(app.COM, app.SPEED);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';           
            R17Close(app.R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
 
            app.COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
        end

        % Callback function
        function Movement1ButtonPushed(app, event)
            
            q2 = [0; 0;deg2rad(90); 0; deg2rad(-45); 0];
            R17fKine(app.R17, q2);
        end

        % Callback function
        function Movement2ButtonPushed(app, event)
            
            q1 = [0; deg2rad(90);0; deg2rad(45); 0; 0];
            R17fKine(app.R17, q1);
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)

            %Extracción características
            app.Pausa=0;
            ContGiro = 0;
            giro = 0;           
            ContMetros = 0;
            Metros = 0;           
            time = 0;
            Th  = app.ThSpinner.Value;
            %ga = 0.4;
            b_act = 0;            
            Tiempo_DMuestra = app.TiempodemuestraEditField.Value;           
            app.flag_terminar=0;
            % Pause to acquire data for 20 seconds
            %pause(10);
            % Read the data from the device      
            app.Lamp.Enable = 'on';           
            while (app.flag_recibir2 ==1) && (app.flag_recibir1==1)&& (app.flag_terminar==0)
                while (time < Tiempo_DMuestra)      %la variable m determina el tiempoque nosotros asignaramos para llenar el buffer
                    pause(1)   % se le da una pausa de 1 segundo por cada vez que pase, para que se logre llenar el buffer en el tiempo que se requiere
                    time = time + 1 ;%variable que aumenta cada 1 segundo.
                    app.Lamp_3.Enable = 'on';
                end
                
                data = read(app.Bit);% tomamos los ultimos valores en el buffer.
                app.Lamp_3.Enable = 'off';
                %n=n+1%contador para ingresar los datos a la tabla.
                time = 0; %se reinicia el contador para la siguiente vez que se llene el buffer
                EMG_raw = data(:,6); %se toma solo la columna de la senal EMG
                EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
                data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
                app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch               
                wt = cwt(app.data_n(300:end));              
                Actividad = max(max(abs(wt)));                               
                app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.34);
                app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
                app.KurtossisEditField.Value = jKurtosis(app.data_n);
                app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
                app.VarianzaEditField.Value = jVariance(app.data_n);
                app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
                app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);               
                b_act = 0 ;
                app.T.ZC(1) = jZeroCrossing(app.data_n,0.1);
                app.T.MAV(1) = jMeanAbsoluteDeviation(app.data_n);
                app.T.Kurtosis(1) = jKurtosis(app.data_n);
                app.T.AverageE(1) = jAverageEnergy(app.data_n);
                app.T.Varianza(1) = jVariance(app.data_n);
                app.T.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.1);
                app.T.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
                
                if  Actividad > Th  &&  b_act == 0
                    %Activar bandera de inicio de actividad
                    b_act = 1;
                    app.SinActividadLabel.Text = "Se Detecto Actividad"
                end
                
                if (b_act ==1) && (app.Pausa==0)                 
                    app.EnpausaLabel.Enable='off';
                    yfit = app.A.predictFcn(app.T);
                    switch yfit
                        case 'WristUp'
                            %                             ContGiro = ContGiro + 1;
                            %                             if ContGiro == 17
                            %                                 ContGiro =1 ;
                            %                             end
                            %                             giro = app.Array_giro(ContGiro) ;
                            %                             q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                            %                             R17fKine(app.R17, q2);
                            
                            app.ClaseLabel.Text = "WristUp"
                            app.Image.ImageSource = imread("WristUp.jpg");
                        case 'HandUp'
                            %                             ContMetros = ContMetros +1;
                            %                             if ContMetros == 12
                            %                                 ContMetros =1;
                            %                             end
                            %                             Metros = app.Metros_CAREER(ContMetros);
                            %                             q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                            %                             R17fKine(app.R17, q2);
                            app.ClaseLabel.Text = "HandUp"
                            app.Image.ImageSource = imread("manita.jfif");
                    end
                    ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                    Epocas = size(app.data_n,2) ; %se toma el tamano del buffer obtenido
                    L = Epocas; %longitud del vector epocas
                    
                    t=linspace(0, Epocas/1000, L);
                    h1 = plot(app.figFpz_2, t, app.data_n) %se plotea esa muestra
                    app.SinActividadLabel.Text = "Sin Actividad"
                end
                
                if app.Pausa ==1;
                    app.EnpausaLabel.Enable='on';
                    
                end
            end
            
        end

        % Callback function
        function ConectarBitalinoSwitchValueChanged(app, event)


        end

        % Value changed function: ConectarBitalinoSwitch
        function ConectarBitalinoSwitchValueChanged2(app, event)
            
            value = app.ConectarBitalinoSwitch.Value;
            
            if strcmp( value,'On')
                app.NombreBit = app.NombreBluetoothBitalinoEditField.Value
                app.Bit = Bitalino(char(app.NombreBit)); %Creamos la conexion con el dispositivo bitalino y esa conexion la volvemos un objeto.
                app.flag_recibir1=1;
                app.Lamp.Enable="on"
            else
                app.Lamp.Enable="off"
                app.flag_recibir1=0;
                
                
                clear all


            end
        end

        % Value changed function: EmpezarStreamingSwitch
        function EmpezarStreamingSwitchValueChanged(app, event)
            value = app.EmpezarStreamingSwitch.Value;
            
            if strcmp( value,'On')
                
                app.flag_recibir2 =1;
                startBackground(app.Bit)
                app.Lamp_2.Enable="on";
                
                
            else
                app.flag_recibir2 =0;
                stopBackground(app.Bit)
                app.Lamp_2.Enable="off";
            end


        end

        % Button pushed function: EscogerEntrenamientoButton
        function EscogerEntrenamientoButtonPushed(app, event)
            [file,folder]=uigetfile('*.mat');
            if isequal(file, 0)
                disp('No se ha seleccionado ningún archivo .mat'); % Display a message or handle the case
                return;
            end
            filename=fullfile(folder,file);
            s=load(filename);
            C= struct2cell(s);
            app.A= cell2mat(C);
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.flag_terminar=1;           
            app.BanderaParaEmpezar=0;
            app.T = table();
            app.data_n=0;
            if app.Pausa == 1
                app.LarecoleccinestenPausaLabel.Visible = 'off';
                app.Pausa=0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Pausa=0; 
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.Pausa=1;
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            value = app.SPEEDEditField.Value;
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab; % Get the selected tab
            if selectedTab == app.BiopacTab
                app.PaneldeControl.Visible = 'off';
                drawnow;
            else
                app.PaneldeControl.Visible = 'on';
                drawnow;
            end
            
        end

        % Callback function
        function Button_4Pushed(app, event)

        end

        % Callback function
        function IniciarButton_2Pushed(app, event)
            app.Lamp_3.Enable = 'on'; % INICIAR ENTRENAMIENTO LAMP
            app.EnpausaLabel.Enable='off';
            
            % Initializing Variables
            app.Pausa=0;
            ContGiro = 0;
            giro = 0;           
            ContMetros = 0;
            Metros = 0;           
            time = 0;
            Th  = 0.2; %app.ThSpinner.Value; %NO SE QUE ES ThSpinner en DATA
            b_act = 0;            
            %Tiempo_DMuestra = app.TiempodemuestraEditField.Value; % DEBO EDITAR DE DONDE SE TOMA
            
            app.flag_terminar=0;
            data = app.A; % Access the 'data' field of the struct
            EMG_raw = data(:,1); %se toma solo la columna de la senal EMG
            
            EMG = EMG_raw*(3.3/1024);%se convierte la senal a la escala de 3.3 V
            data_f = filter(app.F_pb, EMG');                          %Aplicar filtro pasa bandas
            app.data_n = filter(app.F_notch, data_f);                       %Aplicar filtro notch               
            wt = cwt(app.data_n(300:end));              
            Actividad = max(max(abs(wt)));                               
            app.ZeroCrossingEditField.Value = jZeroCrossing(app.data_n,0.34);
            app.MAVEditField.Value = jMeanAbsoluteDeviation(app.data_n);
            app.KurtossisEditField.Value = jKurtosis(app.data_n);
            app.AverageEnergyEditField.Value = jAverageEnergy(app.data_n);
            app.VarianzaEditField.Value = jVariance(app.data_n);
            app.WilsonAmplitudEditField.Value = jWillisonAmplitude(app.data_n,0.01);
            app.MediaCuadraticaEditField.Value = jRootMeanSquare(app.data_n);               
            b_act = 0 ;
            app.T.ZC(1) = jZeroCrossing(app.data_n,0.1);
            app.T.MAV(1) = jMeanAbsoluteDeviation(app.data_n);
            app.T.Kurtosis(1) = jKurtosis(app.data_n);
            app.T.AverageE(1) = jAverageEnergy(app.data_n);
            app.T.Varianza(1) = jVariance(app.data_n);
            app.T.AmplitudWilson(1) = jWillisonAmplitude(app.data_n,0.1);
            app.T.MediaCuadratica(1) = jRootMeanSquare(app.data_n);
            
            if  Actividad > Th  &&  b_act == 0
                %Activar bandera de inicio de actividad
                b_act = 1;
                app.SinActividadLabel.Text = "Se Detecto Actividad"
            end         
            if (b_act ==1) && (app.Pausa==0)                 
                app.EnpausaLabel.Enable='off';
                yfit = app.A.predictFcn(app.T);
                switch yfit
                    case 'WristUp'
                        %                             ContGiro = ContGiro + 1;
                        %                             if ContGiro == 17
                        %                                 ContGiro =1 ;
                        %                             end
                        %                             giro = app.Array_giro(ContGiro) ;
                        %                             q2 = [Metros; deg2rad(giro);0; 0;0; 0];
                        %                             R17fKine(app.R17, q2);
                        
                        app.ClaseLabel.Text = "WristUp"
                        app.Image.ImageSource = imread("WristUp.jpg");
                    case 'HandUp'
                        %                             ContMetros = ContMetros +1;
                        %                             if ContMetros == 12
                        %                                 ContMetros =1;
                        %                             end
                        %                             Metros = app.Metros_CAREER(ContMetros);
                        %                             q2 = [Metros; deg2rad(giro);0; 0; 0; 0];
                        %                             R17fKine(app.R17, q2);
                        app.ClaseLabel.Text = "HandUp"
                        app.Image.ImageSource = imread("manita.jfif");
                end
                ylim(app.figFpz_2, [min(app.data_n) max(app.data_n)]);
                Epocas = size(app.data_n,2) ; % se toma el tamano de data obtenido
                L = Epocas; % longitud del vector epocas
                t=linspace(0, Epocas/1000, L);
                h1 = plot(app.figFpz_2, t, app.data_n) % se plotea esa muestra
                app.SinActividadLabel.Text = "Sin Actividad"
            end
            
            if app.Pausa ==1;
                app.EnpausaLabel.Enable='on';
                
            end
            
        end

        % Callback function
        function OpenFileButtonPushed(app, event)
             % Open a file dialog and allow user to select a .mat file
        [filename, pathname] = uigetfile('*.mat', 'Select a .mat file');        
        % Check if the user clicked Cancel
        if isequal(filename, 0)
            disp('No se ha seleccionado ningún archivo .mat'); % Display a message or handle the case
            return;
        end
        
        % Load the data from the selected .mat file
        filePath = fullfile(pathname, filename);
        s = load(filePath);
        % Check if the loaded data is a cell array
        if iscell(s)
            disp('Cargando...');
            app.loadedData= struct2cell(s);
            app.A= cell2mat(app.loadedData);
            % app.SPEED = app.SPEEDEditField.Value aun no se lo que hace
        else
            disp('El .mat no contiene ningún cell array');
            % Handle different data types
        end
        end

        % Callback function
        function COMDropDown_2ValueChanged(app, event)
            value = app.COMDropDown_2.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', app.COM);
            
        end

        % Button pushed function: CargarmatButton
        function CargarmatButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            
            
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
                app.EntrenarEEGButton.Enable = 'on';
            else
                disp(['User selected ', fullfile(path,file)]);           
                espacio_trabajo_temp = load(strcat(path,file),'Features_result');
                app.Estructura_Caracteristicas = espacio_trabajo_temp.Features_result;
                app.EntrenarEEGButton.Enable = 'off';
                if app.EntrenarButton.Enable == 'off'
                    app.EntrenarButton.Enable = 'on';
                end
            end

        end

        % Value changed function: SegmentosEntrenamiento
        function SegmentosEntrenamientoValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');                   
            end            
            
        end

        % Value changed function: SegmentosValidacion
        function SegmentosValidacionValueChanged(app, event)
           divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');   
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');
            end  
            
        end

        % Value changed function: SegmentosPrueba
        function SegmentosPruebaValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar 100%');    
                uialert(app.UIFigure, message, 'Advertencia', 'Icon', 'warning');
            end  
            
        end

        % Selection changed function: 
        % SeleccionarclasificadorButtonGroup
        function SeleccionarclasificadorButtonGroupSelectionChanged(app, event)
            if (app.RedNeuronalButton.Value == 1)
                app.SegmentosEntrenamiento.Enable = 'on';
                app.SegmentosValidacion.Enable = 'on';
                app.SegmentosPrueba.Enable = 'on';
                app.CapasOcultasDropDown.Enable = 'on';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'on';

                app.NmerodeParticionesKSpinner.Enable = 'off';
                app.KernelaemplearDropDown.Enable = 'off';

            else
                app.SegmentosEntrenamiento.Enable = 'off';
                app.SegmentosValidacion.Enable = 'off';
                app.SegmentosPrueba.Enable = 'off';
                app.CapasOcultasDropDown.Enable = 'off';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'off';

                app.NmerodeParticionesKSpinner.Enable = 'on';
                app.KernelaemplearDropDown.Enable = 'on';
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            Clasificador_Entrenado = struct();
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
            
            %Se lee el vector de opciones, primero se descompone el texto            
            Cellop = strsplit(app.Estructura_Caracteristicas.(['Car_', app.typeofdatastore]).Caracteristicas,',');
            Vop = app.Estructura_Caracteristicas.Vector_Caracteristicas;
            
            % MEJOR NO TOCAR
            if Tipo_car == 1
                %CarWavelet
                Wmadre = app.Estructura_Caracteristicas.OndaMadre;
                Wn = app.Estructura_Caracteristicas.NivelDescomposicion;
            end                        
            
            Clasificador_Entrenado.Clasificador = app.AlgorimoAprendizajeA;
            if(app.RedNeuronalButton.Value ==1)                
                Clasificador_Entrenado.Tipo_Clasificador = 0;                
            else
                Clasificador_Entrenado.Tipo_Clasificador = 1;                
            end
            Clasificador_Entrenado.Caracteristicas.Tipo_Caracteristicas = Tipo_car;
            if (Tipo_car == 1)
                Clasificador_Entrenado.Caracteristicas.Madre = Wmadre;
                Clasificador_Entrenado.Caracteristicas.Descomp = Wn;
            end            
            Clasificador_Entrenado.Vector_op = Vop;
            Clasificador_Entrenado.No_Clases = Tipo_class;  
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Clasificador.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Clasificador_Entrenado')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.UIFigure, message, 'Éxito', 'Icon', 'success');

        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
      %Se verifica tipo de análisis
            app.typeofdatastore = 'gesto_1';

            %empleado para extraer las características
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
                        
            %Se cargan los vectores de caracteristicas correspondientes
            caracteristicas_gesto_1 = app.Estructura_Caracteristicas.Car_gesto_1.VectorCaracteristicas_gesto_1;            
            caracteristicas_gesto_2 =  app.Estructura_Caracteristicas.Car_gesto_2.VectorCaracteristicas_gesto_2;
            
            %Homogeneización de las señales: Ya que puede que las señales
            %tengan distinta duración, puede que tengan una cantidad de
            %ventanas distintas entre ellas. Se acotará a que todas tengan
            %la misma cantidad que la que tenga menor número de ventanas
            
            cantidad_ventanas = [length(caracteristicas_gesto_1),length(caracteristicas_gesto_2)];
            
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
%            % SI QUISIERA AGREGAR MAS GESTOS
%            
%             if (Tipo_class == 3)
%                 caracteristicas_HeadDown = app.Estructura_Caracteristicas.CarHeadDown.VectorCaracteristicasHeadDown;    
%                 cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_HeadDown)];
%             elseif (Tipo_class == 4)
%                 caracteristicas_HeadDown = app.Estructura_Caracteristicas.CarHeadDown.VectorCaracteristicasHeadDown;
%                 caracteristicas_Blink = app.Estructura_Caracteristicas.CarBlink.VectorCaracteristicasBlink;
%                 cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_HeadDown),length(caracteristicas_Blink)];
%             end                       
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
            

            %Se encuentra cuál es la menor cantidad de ventanas entre los
            %vectores involucrados en el análisis, este es el máximo de
            %ventanas que pueden haber. Se acotan los vectores de car.
            cantidad_ventanasmaximas = min(cantidad_ventanas);
            if (Tipo_class ==2)
                caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
                caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
                %Unimos los vectores en una matriz con todas las
                %características
                matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2'];

                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%               POR SI QUISIERA AGREGAR MAS GESTOS AQUI MODIFICO
%             elseif (Tipo_class == 3)
%                 caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                 matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
%                                                   caracteristicas_Preictal'];
%             elseif (Tipo_class == 4)
%                 caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:);
%                 matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
%                                                   caracteristicas_Preictal',caracteristicas_Interictal'];
%             end            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %Se crea y entrena el clasificador especificado
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                if (Tipo_class == 2)
                    vector_etiquetas_emg =[ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
%                 elseif (Tipo_class == 3)
%                     vector_etiquetas_emg =[ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
%                                            zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
%                                            zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
%                 elseif (Tipo_class == 4)
%                     vector_etiquetas_emg =[ones(1,cantidad_ventanasmaximas),zeros(1,3*cantidad_ventanasmaximas);...
%                                            zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
%                                            zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
%                                            zeros(1,3*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
                net = patternnet(CapasOcultas, Func_entrenamiento);
                % Set the number of epochs based on the stored value
                net.trainParam.epochs = app.EpochsEditField.Value;
                net.trainParam.lr = app.LearningRateEditField.Value;
                %Se entrena la red neuronal
                [net,tr] = train(net,matriz_completa_caracteristicas,vector_etiquetas_emg);
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;


                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 20;
                    divival = 10;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                etiquetas_salida = net(matriz_completa_caracteristicas); 
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_emg,etiquetas_salida);
                
                if (Tipo_class == 2)
                    gesto_1_11 = cm(1,1);%gesto_1 correcto
                    gesto_1_12 = cm(1,2);%gesto_1 falso (sano)                   
                    
                    gesto_2_21 = cm(2,1);%gesto_2 falso (ictal)
                    gesto_2_22 = cm(2,2);%gesto_2 correcto
                    
                    accu = num2str(100*((gesto_1_11+gesto_2_22) / (gesto_1_11+gesto_1_12+...
                                        gesto_2_21+gesto_2_22)));  %Exactitud
                    figure;
                    plotconfusion(vector_etiquetas_emg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Exactitud'})
                    set(gca,'yticklabel',{'gesto_1' 'gesto_2' ''})                                        
                    app.ExactituddelclasificadorLabel.Text = append("Classifier Accuracy: ",num2str(accu),"%");
                    figure;
                    plotperform(tr);
                    

                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
%                 elseif (Tipo_class == 3)
%                     gesto_1_11 = cm(1,1);%gesto_1 correcto
%                     gesto_1_12 = cm(1,2);%gesto_1 falso (sano)
%                     gesto_1_13 = cm(1,3); %gesto_1 falso (preict)                    
%                     
%                     gesto_2_21 = cm(2,1);%gesto_2 falso (ictal)
%                     gesto_2_22 = cm(2,2);%gesto_2 correcto
%                     gesto_2_23 = cm(2,3);%gesto_2 falso (preict)
%                     
%                     Pre_31 = cm(3,1);%Preictal falso (ictal)
%                     Pre_32 = cm(3,2);%Preictal falso (sano)
%                     Pre_33 = cm(3,3);%Preictal correcto
%                     
%                     accu = num2str(100*((gesto_1_11+gesto_2_22+Pre_33) / (gesto_1_11+gesto_1_12+gesto_1_13+...
%                                         gesto_2_21+gesto_2_22+gesto_2_23+...    
%                                         Pre_31+Pre_32+Pre_33)));  %Exactitud
%                     plotconfusion(vector_etiquetas_emg,etiquetas_salida,{'Matriz de Confusión'});
%                     set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'gesto_1' 'gesto_2' 'Preictal' ''})
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 elseif (Tipo_class == 4)
%                     gesto_1_11 = cm(1,1);%gesto_1 correcto
%                     gesto_1_12 = cm(1,2);%gesto_1 falso (sano)
%                     gesto_1_13 = cm(1,3); %gesto_1 falso (preict)
%                     gesto_1_14 = cm(1,4);%gesto_1 falso (inter)
%                     
%                     gesto_2_21 = cm(2,1);%gesto_2 falso (ictal)
%                     gesto_2_22 = cm(2,2);%gesto_2 correcto
%                     gesto_2_23 = cm(2,3);%gesto_2 falso (preict)
%                     gesto_2_24 = cm(2,4);%gesto_2 falso (inter)
%                     
%                     Pre_31 = cm(3,1);%Preictal falso (ictal)
%                     Pre_32 = cm(3,2);%Preictal falso (sano)
%                     Pre_33 = cm(3,3);%Preictal correcto
%                     Pre_34 = cm(3,4);%Preictal falso (inter)
%                     
%                     Inter_41 = cm(4,1);%Interictal falso (ictal)
%                     Inter_42 = cm(4,2);%Interictal falso (sano)
%                     Inter_43 = cm(4,3);%Interictal falso (preict)
%                     Inter_44 = cm(4,4);%Interictal correcto
%                     
%                     accu = num2str(100*((gesto_1_11+gesto_2_22+Pre_33+Inter_44) / (gesto_1_11+gesto_1_12+gesto_1_13+gesto_1_14+...
%                                         gesto_2_21+gesto_2_22+gesto_2_23+gesto_2_24+...    
%                                         Pre_31+Pre_32+Pre_33+Pre_34+...
%                                         Inter_41+Inter_42+Inter_43+Inter_44)));  %Exactitud
%                     plotconfusion(vector_etiquetas_emg,etiquetas_salida,{'Matriz de Confusión'});
%                     set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Interictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Interictal' ''})
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",accu,"%");
%                 end    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  


            else (app.SVMButton.Value == 1) %SVM
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:)];
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
%                 elseif (Tipo_class == 3)
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                 elseif (Tipo_class == 4)                   
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %Se transpone la matriz de características para este
                %clasificador (SVM)
                matriz_completa_caracteristicas = matriz_completa_caracteristicas';
                
                %Validacion cruzada para el entrenamiento del clasificador
                k = app.NmerodeParticionesKSpinner.Value;
                cvFolds = crossvalind('Kfold', vector_etiquetas_emg, k);   
                cp = classperf(vector_etiquetas_emg);        %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx;
                    %Obtener conjuntos train y test
                    datos_entrenamiento = matriz_completa_caracteristicas(trainIdx,:);
                    etiquetas_entrenamiento = vector_etiquetas_emg(trainIdx,:);
                    datos_prueba = matriz_completa_caracteristicas(testIdx,:);
                    etiquetas_prueba = vector_etiquetas_emg(testIdx,:);                  
                end
                
                %Parámetros y entrenamiento de la SVM
                kernel = app.KernelaemplearDropDown.Value;
                t_SVM = templateSVM('Standardize',true,'KernelFunction',kernel);
                model_svm = fitcecoc(datos_entrenamiento,etiquetas_entrenamiento,'Learners',t_SVM,'FitPosterior',true,...
                    'Verbose',2);
                [labelPred,~] =  predict(model_svm, datos_prueba);
                etiquetas_generadas = labelPred;                
                Prueba_eti = categorical(etiquetas_prueba');
                Predict_eti = categorical(etiquetas_generadas');
                app.AlgorimoAprendizajeA = model_svm;
                %Graficamos la matriz de confusión
                plotconfusion(Prueba_eti,Predict_eti,{'Matriz de Confusión'});
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Exactitud'})
                    set(gca,'yticklabel',{'gesto_1' 'gesto_2' ''})
                    %Calculamos la exactitud del clasificador
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                end 
                
                % MAS GESTOS
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 elseif (Tipo_class == 3)
%                     set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'gesto_1' 'gesto_2' 'Preictal' ''})
%                     matriz = confusionmat(Prueba_eti,Predict_eti);
%                     s = sum(matriz);
%                     accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3))/(s(1)+s(2)+s(3));
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 elseif (Tipo_class == 4)                   
%                     set(gca,'xticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Interictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'gesto_1' 'gesto_2' 'Preictal' 'Interictal' ''})
%                     matriz = confusionmat(Prueba_eti,Predict_eti);
%                     s = sum(matriz);
%                     accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3)+matriz(4,4))/(s(1)+s(2)+s(3)+s(4));
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 end 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            % MAS OPCIONES DE ENTRENAMIENTO
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             elseif (app.KmeansButton.Value == 1) %Kmeans cluster
%                 if (Tipo_class == 2)
%                     m = 2;
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_gesto_1 = app.Estructura_Caracteristicas.Car_gesto_1.VectorCaracteristicas_gesto_1;
%                     caracteristicas_gesto_2 =  app.Estructura_Caracteristicas.Car_gesto_2.VectorCaracteristicas_gesto_2;
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_gesto_1;caracteristicas_gesto_2],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_2clases_kmeans = Rand_index(E2, vector_etiquetas_emg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_2clases_kmeans * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                 elseif (Tipo_class == 3)
%                     m = 3;
%                     
%                     caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_gesto_1; caracteristicas_gesto_2;caracteristicas_Preictal],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_3clases_kmeans = Rand_index(E2, vector_etiquetas_emg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_3clases_kmeans * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                   elseif (Tipo_class == 4) 
%                     m = 4;
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:)                 
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                    %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_gesto_1;caracteristicas_gesto_2;caracteristicas_Interictal;caracteristicas_Interictal],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_4clases_kmeans = Rand_index(E2, vector_etiquetas_emg); 
%                    
%                    %Redondear la exactitud del clasificador (Validación)
%                    porcentajerands = round((rands_4clases_kmeans * 100),2);
%                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");   
%                 end 
%             else %Jerarquico
%                 
%                   %Se verifica si se desean 2, 3 o 4 clases y el tipo de análisis
%                     %empleado para extraer las características
%                     Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
%                     Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
%                     
%                 if (Tipo_class == 2)
%                   
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_gesto_1 = app.Estructura_Caracteristicas.Car_gesto_1.VectorCaracteristicas_gesto_1;
%                     caracteristicas_gesto_2 =  app.Estructura_Caracteristicas.Car_gesto_2.VectorCaracteristicas_gesto_2;
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por clustering jerarquico
%                     ClusteringJerarquico = clusterdata([caracteristicas_gesto_1;caracteristicas_gesto_2],'Linkage','ward','SaveMemory','on','Maxclust',2);                    
%                     %Rand index para obtener exactitud del clasificador
%                     rands_2clases = Rand_index(ClusteringJerarquico, vector_etiquetas_emg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_2clases * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                     
%                 elseif (Tipo_class == 3)
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
%                                                   caracteristicas_Preictal'];
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por clustering jerarquico
%                     ClusteringJerarquico = clusterdata([caracteristicas_gesto_1; caracteristicas_gesto_2;caracteristicas_Preictal],'Linkage','ward','SaveMemory','on','Maxclust',3);                    
%                     %Rand index para obtener exactitud del clasificador
%                     rands_3clases = Rand_index(ClusteringJerarquico, vector_etiquetas_emg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_3clases * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                     
%                 elseif (Tipo_class == 4) 
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_gesto_1 = caracteristicas_gesto_1(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_gesto_2 = caracteristicas_gesto_2(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:);
%                     matriz_completa_caracteristicas =[caracteristicas_gesto_1',caracteristicas_gesto_2',...
%                                                   caracteristicas_Preictal',caracteristicas_Interictal'];
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_emg = [app.Estructura_Caracteristicas.Car_gesto_1.Etiquetas_gesto_1(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_gesto_2.Etiquetas_gesto_2(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                     
%                    %Entrenamiento por clustering jerarquico
%                    ClusteringJerarquico = clusterdata([caracteristicas_gesto_1;caracteristicas_gesto_2;caracteristicas_Interictal;caracteristicas_Interictal],'Linkage','ward','SaveMemory','on','Maxclust',4);                    
%                    %Rand index para obtener exactitud del clasificador
%                    rands_4clases = Rand_index(ClusteringJerarquico, vector_etiquetas_emg);
%                    %Redondear la exactitud del clasificador (Validación)
%                    porcentajerands = round((rands_4clases * 100),2);
%                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                 end
%                 
            end
            end
                    
        end

        % Callback function
        function EntrenarEEGButtonPushed(app, event)
            %Se verifica tipo de análisis
            %empleado para extraer las características
            app.typeofdatastore = 'HeadDown';
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
                        
            %Se cargan los vectores de caracteristicas correspondientes
            caracteristicas_HeadDown = app.Estructura_Caracteristicas.Car_HeadDown.VectorCaracteristicas_HeadDown;            
            caracteristicas_Blink =  app.Estructura_Caracteristicas.Car_Blink.VectorCaracteristicas_Blink;
            
            %Homogeneización de las señales: Ya que puede que las señales
            %tengan distinta duración, puede que tengan una cantidad de
            %ventanas distintas entre ellas. Se acotará a que todas tengan
            %la misma cantidad que la que tenga menor número de ventanas
            
            cantidad_ventanas = [length(caracteristicas_HeadDown),length(caracteristicas_Blink)];
            
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
%            % SI QUISIERA AGREGAR MAS GESTOS
%            
%             if (Tipo_class == 3)
%                 caracteristicas_HeadDown = app.Estructura_Caracteristicas.CarHeadDown.VectorCaracteristicasHeadDown;    
%                 cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_HeadDown)];
%             elseif (Tipo_class == 4)
%                 caracteristicas_HeadDown = app.Estructura_Caracteristicas.CarHeadDown.VectorCaracteristicasHeadDown;
%                 caracteristicas_Blink = app.Estructura_Caracteristicas.CarBlink.VectorCaracteristicasBlink;
%                 cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_HeadDown),length(caracteristicas_Blink)];
%             end                       
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
            

            %Se encuentra cuál es la menor cantidad de ventanas entre los
            %vectores involucrados en el análisis, este es el máximo de
            %ventanas que pueden haber. Se acotan los vectores de car.
            cantidad_ventanasmaximas = min(cantidad_ventanas);
            if (Tipo_class ==2)
                caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
                caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
                %Unimos los vectores en una matriz con todas las
                %características
                matriz_completa_caracteristicas =[caracteristicas_HeadDown',caracteristicas_Blink'];

                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%               POR SI QUISIERA AGREGAR MAS GESTOS AQUI MODIFICO
%             elseif (Tipo_class == 3)
%                 caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                 matriz_completa_caracteristicas =[caracteristicas_HeadDown',caracteristicas_Blink',...
%                                                   caracteristicas_Preictal'];
%             elseif (Tipo_class == 4)
%                 caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                 caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:);
%                 matriz_completa_caracteristicas =[caracteristicas_HeadDown',caracteristicas_Blink',...
%                                                   caracteristicas_Preictal',caracteristicas_Interictal'];
%             end            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %Se crea y entrena el clasificador especificado
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                if (Tipo_class == 2)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
%                 elseif (Tipo_class == 3)
%                     vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
%                                            zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
%                                            zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
%                 elseif (Tipo_class == 4)
%                     vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,3*cantidad_ventanasmaximas);...
%                                            zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
%                                            zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
%                                            zeros(1,3*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
              
                net = patternnet(CapasOcultas, Func_entrenamiento);
                % Set the number of epochs based on the stored value
                net.trainParam.epochs = app.EpochsEditField.Value;
                net.trainParam.lr = app.LearningRateEditField.Value;
                %Se entrena la red neuronal
                
                [net,tr] = train(net,matriz_completa_caracteristicas,vector_etiquetas_eeg);
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;


                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 15;
                    divival = 15;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                etiquetas_salida = net(matriz_completa_caracteristicas); 
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_eeg,etiquetas_salida);
                
                if (Tipo_class == 2)
                    HeadDown_11 = cm(1,1);%HeadDown correcto
                    HeadDown_12 = cm(1,2);%HeadDown falso (Blink)                   
                    
                    Blink_21 = cm(2,1);%Blink falso (HeadDown)
                    Blink_22 = cm(2,2);%Blink correcto
                    
                    accu = num2str(100*((HeadDown_11+Blink_22) / (HeadDown_11+HeadDown_12+...
                                        Blink_21+Blink_22)));  %Exactitud
                    figure;
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'HeadDown' 'Blink' 'Exactitud'})
                    set(gca,'yticklabel',{'HeadDown' 'Blink' ''})                                        
                    app.ExactituddelclasificadorLabel.Text = append("Classifier Accuracy: ",num2str(accu),"%");
                    figure;
                    plotperform(tr);
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
%                 elseif (Tipo_class == 3)
%                     HeadDown_11 = cm(1,1);%HeadDown correcto
%                     HeadDown_12 = cm(1,2);%HeadDown falso (sano)
%                     HeadDown_13 = cm(1,3); %HeadDown falso (preict)                    
%                     
%                     Blink_21 = cm(2,1);%Blink falso (ictal)
%                     Blink_22 = cm(2,2);%Blink correcto
%                     Blink_23 = cm(2,3);%Blink falso (preict)
%                     
%                     Pre_31 = cm(3,1);%Preictal falso (ictal)
%                     Pre_32 = cm(3,2);%Preictal falso (sano)
%                     Pre_33 = cm(3,3);%Preictal correcto
%                     
%                     accu = num2str(100*((HeadDown_11+Blink_22+Pre_33) / (HeadDown_11+HeadDown_12+HeadDown_13+...
%                                         Blink_21+Blink_22+Blink_23+...    
%                                         Pre_31+Pre_32+Pre_33)));  %Exactitud
%                     plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
%                     set(gca,'xticklabel',{'HeadDown' 'Blink' 'Preictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'HeadDown' 'Blink' 'Preictal' ''})
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 elseif (Tipo_class == 4)
%                     HeadDown_11 = cm(1,1);%HeadDown correcto
%                     HeadDown_12 = cm(1,2);%HeadDown falso (sano)
%                     HeadDown_13 = cm(1,3); %HeadDown falso (preict)
%                     HeadDown_14 = cm(1,4);%HeadDown falso (inter)
%                     
%                     Blink_21 = cm(2,1);%Blink falso (ictal)
%                     Blink_22 = cm(2,2);%Blink correcto
%                     Blink_23 = cm(2,3);%Blink falso (preict)
%                     Blink_24 = cm(2,4);%Blink falso (inter)
%                     
%                     Pre_31 = cm(3,1);%Preictal falso (ictal)
%                     Pre_32 = cm(3,2);%Preictal falso (sano)
%                     Pre_33 = cm(3,3);%Preictal correcto
%                     Pre_34 = cm(3,4);%Preictal falso (inter)
%                     
%                     Inter_41 = cm(4,1);%Interictal falso (ictal)
%                     Inter_42 = cm(4,2);%Interictal falso (sano)
%                     Inter_43 = cm(4,3);%Interictal falso (preict)
%                     Inter_44 = cm(4,4);%Interictal correcto
%                     
%                     accu = num2str(100*((HeadDown_11+Blink_22+Pre_33+Inter_44) / (HeadDown_11+HeadDown_12+HeadDown_13+HeadDown_14+...
%                                         Blink_21+Blink_22+Blink_23+Blink_24+...    
%                                         Pre_31+Pre_32+Pre_33+Pre_34+...
%                                         Inter_41+Inter_42+Inter_43+Inter_44)));  %Exactitud
%                     plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
%                     set(gca,'xticklabel',{'HeadDown' 'Blink' 'Preictal' 'Interictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'HeadDown' 'Blink' 'Preictal' 'Interictal' ''})
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",accu,"%");
%                 end    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  


            else (app.SVMButton.Value == 1) %SVM
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:)];
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
%                 elseif (Tipo_class == 3)
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                 elseif (Tipo_class == 4)                   
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %Se transpone la matriz de características para este
                %clasificador (SVM)
                matriz_completa_caracteristicas = matriz_completa_caracteristicas';
                
                %Validacion cruzada para el entrenamiento del clasificador
                k = app.NmerodeParticionesKSpinner.Value;
                cvFolds = crossvalind('Kfold', vector_etiquetas_eeg, k);   
                cp = classperf(vector_etiquetas_eeg);        %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx;
                    %Obtener conjuntos train y test
                    datos_entrenamiento = matriz_completa_caracteristicas(trainIdx,:);
                    etiquetas_entrenamiento = vector_etiquetas_eeg(trainIdx,:);
                    datos_prueba = matriz_completa_caracteristicas(testIdx,:);
                    etiquetas_prueba = vector_etiquetas_eeg(testIdx,:);                  
                end
                
                %Parámetros y entrenamiento de la SVM
                kernel = app.KernelaemplearDropDown.Value;
                t_SVM = templateSVM('Standardize',true,'KernelFunction',kernel);
                model_svm = fitcecoc(datos_entrenamiento,etiquetas_entrenamiento,'Learners',t_SVM,'FitPosterior',true,...
                    'Verbose',2);
                [labelPred,~] =  predict(model_svm, datos_prueba);
                etiquetas_generadas = labelPred;                
                Prueba_eti = categorical(etiquetas_prueba');
                Predict_eti = categorical(etiquetas_generadas');
                app.AlgorimoAprendizajeA = model_svm;
                %Graficamos la matriz de confusión
                plotconfusion(Prueba_eti,Predict_eti,{'Matriz de Confusión'});
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    set(gca,'xticklabel',{'HeadDown' 'Blink' 'Exactitud'})
                    set(gca,'yticklabel',{'HeadDown' 'Blink' ''})
                    %Calculamos la exactitud del clasificador
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                end 
                
                % MAS GESTOS
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 elseif (Tipo_class == 3)
%                     set(gca,'xticklabel',{'HeadDown' 'Blink' 'Preictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'HeadDown' 'Blink' 'Preictal' ''})
%                     matriz = confusionmat(Prueba_eti,Predict_eti);
%                     s = sum(matriz);
%                     accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3))/(s(1)+s(2)+s(3));
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 elseif (Tipo_class == 4)                   
%                     set(gca,'xticklabel',{'HeadDown' 'Blink' 'Preictal' 'Interictal' 'Exactitud'})
%                     set(gca,'yticklabel',{'HeadDown' 'Blink' 'Preictal' 'Interictal' ''})
%                     matriz = confusionmat(Prueba_eti,Predict_eti);
%                     s = sum(matriz);
%                     accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3)+matriz(4,4))/(s(1)+s(2)+s(3)+s(4));
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
%                 end 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            % MAS OPCIONES DE ENTRENAMIENTO
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             elseif (app.KmeansButton.Value == 1) %Kmeans cluster
%                 if (Tipo_class == 2)
%                     m = 2;
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_HeadDown = app.Estructura_Caracteristicas.Car_HeadDown.VectorCaracteristicas_HeadDown;
%                     caracteristicas_Blink =  app.Estructura_Caracteristicas.Car_Blink.VectorCaracteristicas_Blink;
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_HeadDown;caracteristicas_Blink],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_2clases_kmeans = Rand_index(E2, vector_etiquetas_eeg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_2clases_kmeans * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                 elseif (Tipo_class == 3)
%                     m = 3;
%                     
%                     caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_HeadDown; caracteristicas_Blink;caracteristicas_Preictal],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_3clases_kmeans = Rand_index(E2, vector_etiquetas_eeg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_3clases_kmeans * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                   elseif (Tipo_class == 4) 
%                     m = 4;
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:)                 
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                    %Entrenamiento por kmeans
%                     [E2, centros2] = k_means([caracteristicas_HeadDown;caracteristicas_Blink;caracteristicas_Interictal;caracteristicas_Interictal],m);                       
%                     %Rand index para obtener exactitud del clasificador
%                     rands_4clases_kmeans = Rand_index(E2, vector_etiquetas_eeg); 
%                    
%                    %Redondear la exactitud del clasificador (Validación)
%                    porcentajerands = round((rands_4clases_kmeans * 100),2);
%                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");   
%                 end 
%             else %Jerarquico
%                 
%                   %Se verifica si se desean 2, 3 o 4 clases y el tipo de análisis
%                     %empleado para extraer las características
%                     Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
%                     Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
%                     
%                 if (Tipo_class == 2)
%                   
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_HeadDown = app.Estructura_Caracteristicas.Car_HeadDown.VectorCaracteristicas_HeadDown;
%                     caracteristicas_Blink =  app.Estructura_Caracteristicas.Car_Blink.VectorCaracteristicas_Blink;
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por clustering jerarquico
%                     ClusteringJerarquico = clusterdata([caracteristicas_HeadDown;caracteristicas_Blink],'Linkage','ward','SaveMemory','on','Maxclust',2);                    
%                     %Rand index para obtener exactitud del clasificador
%                     rands_2clases = Rand_index(ClusteringJerarquico, vector_etiquetas_eeg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_2clases * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                     
%                 elseif (Tipo_class == 3)
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     matriz_completa_caracteristicas =[caracteristicas_HeadDown',caracteristicas_Blink',...
%                                                   caracteristicas_Preictal'];
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
%                     
%                     %Entrenamiento por clustering jerarquico
%                     ClusteringJerarquico = clusterdata([caracteristicas_HeadDown; caracteristicas_Blink;caracteristicas_Preictal],'Linkage','ward','SaveMemory','on','Maxclust',3);                    
%                     %Rand index para obtener exactitud del clasificador
%                     rands_3clases = Rand_index(ClusteringJerarquico, vector_etiquetas_eeg);
%                     %Redondear la exactitud del clasificador (Validación)
%                     porcentajerands = round((rands_3clases * 100),2);
%                     app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                     
%                 elseif (Tipo_class == 4) 
%                     %Se cargan los vectores de caracteristicas correspondientes
%                     caracteristicas_HeadDown = caracteristicas_HeadDown(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Blink = caracteristicas_Blink(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
%                     caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:);
%                     matriz_completa_caracteristicas =[caracteristicas_HeadDown',caracteristicas_Blink',...
%                                                   caracteristicas_Preictal',caracteristicas_Interictal'];
%                     
%                     %Creacion de etiquetas
%                     vector_etiquetas_eeg = [app.Estructura_Caracteristicas.Car_HeadDown.Etiquetas_HeadDown(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.Car_Blink.Etiquetas_Blink(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
%                                             app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
%                     
%                    %Entrenamiento por clustering jerarquico
%                    ClusteringJerarquico = clusterdata([caracteristicas_HeadDown;caracteristicas_Blink;caracteristicas_Interictal;caracteristicas_Interictal],'Linkage','ward','SaveMemory','on','Maxclust',4);                    
%                    %Rand index para obtener exactitud del clasificador
%                    rands_4clases = Rand_index(ClusteringJerarquico, vector_etiquetas_eeg);
%                    %Redondear la exactitud del clasificador (Validación)
%                    porcentajerands = round((rands_4clases * 100),2);
%                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(porcentajerands),"%");
%                 end
%                 
            end
            end
        
        end

        % Callback function
        function CargarmatButton_2Pushed(app, event)
              %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
                app.EntrenarButton.Enable = 'on';
            else
                disp(['User selected ', fullfile(path,file)]);     
                espacio_trabajo_temp = load(strcat(path,file),'Features_result');
                app.Estructura_Caracteristicas = espacio_trabajo_temp.Features_result;
                app.EntrenarButton.Enable = 'off';
                if app.EntrenarEEGButton.Enable == 'off'
                    app.EntrenarEEGButton.Enable = 'on';
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BCIEMGEEGUVGLabel = uilabel(app.UIFigure);
            app.BCIEMGEEGUVGLabel.Text = 'BCI EMG/EEG - UVG';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BitalinoBluetoothTab = uitab(app.TabGroup);
            app.BitalinoBluetoothTab.Title = 'Bitalino Bluetooth';
            app.PaneldeControl = uipanel(app.BitalinoBluetoothTab);
            app.PaneldeControl.Title = 'Panel de Control';
            app.ParametersPanel = uipanel(app.PaneldeControl);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.PaneldeControl);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.IniciarButton = uibutton(app.PaneldeControl, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar ';
            app.TiempodemuestraEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraEditFieldLabel.Text = 'Tiempo de muestra';
            app.TiempodemuestraEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.TiempodemuestraEditField.Limits = [1 Inf];
            app.TiempodemuestraEditField.Value = 3;
            app.ThSpinnerLabel = uilabel(app.PaneldeControl);
            app.ThSpinnerLabel.Text = 'Th';
            app.ThSpinner = uispinner(app.PaneldeControl);
            app.ThSpinner.Step = 0.01;
            app.ThSpinner.Limits = [0 0.5];
            app.ThSpinner.Value = 0.2;
            app.Lamp = uilamp(app.PaneldeControl);
            app.Lamp_2 = uilamp(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel = uilabel(app.PaneldeControl);
            app.ConectarBitalinoSwitchLabel.Text = 'Conectar Bitalino';
            app.ConectarBitalinoSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.ConectarBitalinoSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarBitalinoSwitchValueChanged2, true);
            app.EmpezarStreamingSwitchLabel = uilabel(app.PaneldeControl);
            app.EmpezarStreamingSwitchLabel.Text = 'Empezar Streaming';
            app.EmpezarStreamingSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.EmpezarStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @EmpezarStreamingSwitchValueChanged, true);
            app.NombreBluetoothBitalinoEditFieldLabel = uilabel(app.PaneldeControl);
            app.NombreBluetoothBitalinoEditFieldLabel.Text = 'Nombre Bluetooth Bitalino';
            app.NombreBluetoothBitalinoEditField = uieditfield(app.PaneldeControl, 'text');
            app.NombreBluetoothBitalinoEditField.Value = 'BITalino-00-97';
            app.PararButton = uibutton(app.PaneldeControl, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.EscogerEntrenamientoButton = uibutton(app.PaneldeControl, 'push');
            app.EscogerEntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EscogerEntrenamientoButtonPushed, true);
            app.EscogerEntrenamientoButton.Text = 'Escoger Entrenamiento';
            app.Button = uibutton(app.PaneldeControl, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = 'BotonPausa.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.PaneldeControl, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.BiopacTab = uitab(app.TabGroup);
            app.BiopacTab.Title = 'Biopac';
            app.Biopac_Image = uiimage(app.BiopacTab);
            app.Biopac_Image.ImageSource = 'Biopac_Image.jpeg';
            app.VectorCaractersticasEMGEEGLabel = uilabel(app.BiopacTab);
            app.VectorCaractersticasEMGEEGLabel.Text = 'Vector Características EMG/EEG';
            app.SeleccionarclasificadorButtonGroup = uibuttongroup(app.BiopacTab);
            app.SeleccionarclasificadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccionarclasificadorButtonGroupSelectionChanged, true);
            app.SeleccionarclasificadorButtonGroup.Title = 'Seleccionar Clasificador';
            app.RedNeuronalButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.RedNeuronalButton.Text = 'Red Neuronal';
            app.RedNeuronalButton.Value = true;
            app.SVMButton = uitogglebutton(app.SeleccionarclasificadorButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.ClasificadorRedNeuronalLabel = uilabel(app.BiopacTab);
            app.ClasificadorRedNeuronalLabel.Text = 'Clasificador Red Neuronal';
            app.EntrenamientoEditFieldLabel = uilabel(app.BiopacTab);
            app.EntrenamientoEditFieldLabel.Text = 'Entrenamiento';
            app.SegmentosEntrenamiento = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosEntrenamiento.ValueChangedFcn = createCallbackFcn(app, @SegmentosEntrenamientoValueChanged, true);
            app.SegmentosEntrenamiento.Value = 70;
            app.SegmentosValidacion = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosValidacion.ValueChangedFcn = createCallbackFcn(app, @SegmentosValidacionValueChanged, true);
            app.SegmentosValidacion.Value = 20;
            app.ValidacionEditFieldLabel = uilabel(app.BiopacTab);
            app.ValidacionEditFieldLabel.Text = 'Validacion';
            app.SegmentosPrueba = uieditfield(app.BiopacTab, 'numeric');
            app.SegmentosPrueba.ValueChangedFcn = createCallbackFcn(app, @SegmentosPruebaValueChanged, true);
            app.SegmentosPrueba.Value = 10;
            app.PruebaEditFieldLabel = uilabel(app.BiopacTab);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.Label = uilabel(app.BiopacTab);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.BiopacTab);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.BiopacTab);
            app.Label_3.Text = '%';
            app.AlgoritmoRNADropDownLabel = uilabel(app.BiopacTab);
            app.AlgoritmoRNADropDownLabel.Text = 'Algoritmo RNA';
            app.AlgoritmoparaelentrenamientoDropDown = uidropdown(app.BiopacTab);
            app.AlgoritmoparaelentrenamientoDropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd'};
            app.AlgoritmoparaelentrenamientoDropDown.Value = 'trainscg';
            app.CapasOcultasDropDownLabel = uilabel(app.BiopacTab);
            app.CapasOcultasDropDownLabel.Text = 'Capas Ocultas';
            app.CapasOcultasDropDown = uidropdown(app.BiopacTab);
            app.CapasOcultasDropDown.Items = {'2', '4', '8', '10', '12', '15'};
            app.CapasOcultasDropDown.Value = '2';
            app.MaquinaSoporteVectorialSVMLabel = uilabel(app.BiopacTab);
            app.MaquinaSoporteVectorialSVMLabel.Text = 'Maquina Soporte Vectorial (SVM)';
            app.ValidacinCruzadaLabel = uilabel(app.BiopacTab);
            app.ValidacinCruzadaLabel.Text = 'Validación Cruzada: ';
            app.KfoldsLabel = uilabel(app.BiopacTab);
            app.KfoldsLabel.Enable = 'off';
            app.KfoldsLabel.Text = 'K-folds';
            app.NmerodeParticionesKSpinner = uispinner(app.BiopacTab);
            app.NmerodeParticionesKSpinner.Limits = [2 10];
            app.NmerodeParticionesKSpinner.Enable = 'off';
            app.NmerodeParticionesKSpinner.Value = 2;
            app.KernelDropDownLabel = uilabel(app.BiopacTab);
            app.KernelDropDownLabel.Enable = 'off';
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelaemplearDropDown = uidropdown(app.BiopacTab);
            app.KernelaemplearDropDown.Items = {'Gaussian', 'Linear', ''};
            app.KernelaemplearDropDown.Enable = 'off';
            app.KernelaemplearDropDown.Value = 'Gaussian';
            app.CargarmatButton = uibutton(app.BiopacTab, 'push');
            app.CargarmatButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatButtonPushed, true);
            app.CargarmatButton.Text = 'Cargar.mat';
            app.EntrenarButton = uibutton(app.BiopacTab, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ExactituddelclasificadorLabel = uilabel(app.BiopacTab);
            app.ExactituddelclasificadorLabel.Text = 'Classifier Accuracy:  ?';
            app.SaveAsButton = uibutton(app.BiopacTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.EpochsEditFieldLabel = uilabel(app.BiopacTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.BiopacTab, 'numeric');
            app.EpochsEditField.Limits = [1 500];
            app.EpochsEditField.Value = 10;
            app.LearningRateEditFieldLabel = uilabel(app.BiopacTab);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.BiopacTab, 'numeric');
            app.LearningRateEditField.Limits = [0.001 0.1];
            app.LearningRateEditField.Value = 0.001;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SealesenTiempoRealBitalinoTab = uitab(app.TabGroup2);
            app.SealesenTiempoRealBitalinoTab.Title = 'Señales en Tiempo Real - Bitalino';
            app.SealesentiempoRealPanel = uipanel(app.SealesenTiempoRealBitalinoTab);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'V')
            app.Canal1Label = uilabel(app.SealesentiempoRealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.ZeroCrossingEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.ZeroCrossingEditFieldLabel.Text = 'Zero Crossing';
            app.ZeroCrossingEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MAVEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.KurtossisEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.KurtossisEditFieldLabel.Text = 'Kurtossis';
            app.KurtossisEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.AverageEnergyEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.AverageEnergyEditFieldLabel.Text = 'Average Energy';
            app.AverageEnergyEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.VarianzaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.WilsonAmplitudEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.WilsonAmplitudEditFieldLabel.Text = 'Wilson Amplitud';
            app.WilsonAmplitudEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.MediaCuadraticaEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.MediaCuadraticaEditFieldLabel.Text = 'Media Cuadratica';
            app.MediaCuadraticaEditField = uieditfield(app.SealesentiempoRealPanel, 'numeric');
            app.Lamp_3 = uilamp(app.SealesentiempoRealPanel);
            app.EnpausaLabel = uilabel(app.SealesentiempoRealPanel);
            app.EnpausaLabel.Text = 'En pausa';
            app.Image = uiimage(app.SealesentiempoRealPanel);
            app.ClaseLabel = uilabel(app.SealesentiempoRealPanel);
            app.ClaseLabel.Text = 'Clase';
            app.SinActividadLabel = uilabel(app.SealesentiempoRealPanel);
            app.SinActividadLabel.Text = 'Sin Actividad';
            app.RobotBiopacTab = uitab(app.TabGroup2);
            app.RobotBiopacTab.Title = 'Robot - Biopac';
            app.UIAxes = uiaxes(app.RobotBiopacTab);
            title(app.UIAxes, 'EMG Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CargarmatButton_4 = uibutton(app.RobotBiopacTab, 'push');
            app.CargarmatButton_4.Text = 'Cargar .mat';
            app.CargarEntrenamientoButton = uibutton(app.RobotBiopacTab, 'push');
            app.CargarEntrenamientoButton.Text = 'Cargar Entrenamiento';
            app.IniciarButton_2 = uibutton(app.RobotBiopacTab, 'push');
            app.IniciarButton_2.Text = 'Iniciar';
            app.SealEMGLabel = uilabel(app.RobotBiopacTab);
            app.SealEMGLabel.Text = 'Señal EMG';
            app.CargarmatButton_3 = uibutton(app.RobotBiopacTab, 'push');
            app.CargarmatButton_3.Text = 'Cargar .mat';
            app.SealEEGLabel = uilabel(app.RobotBiopacTab);
            app.SealEEGLabel.Text = 'Señal EEG';
            app.CargarEntrenamientoButton_2 = uibutton(app.RobotBiopacTab, 'push');
            app.CargarEntrenamientoButton_2.Text = 'Cargar Entrenamiento';
            app.SimulacinLabel = uilabel(app.RobotBiopacTab);
            app.SimulacinLabel.Text = 'Simulación';
            app.EEGDropDownLabel = uilabel(app.RobotBiopacTab);
            app.EEGDropDownLabel.Text = 'EEG';
            app.EEGDropDown = uidropdown(app.RobotBiopacTab);
            app.EEGDropDown.Items = {'raw_signal', 'Alpha', 'Beta', 'Delta', 'Theta'};
            app.EEGDropDown.Value = 'raw_signal';
            app.VisualizacinLabel = uilabel(app.RobotBiopacTab);
            app.VisualizacinLabel.Text = 'Visualización';
            app.Image2 = uiimage(app.RobotBiopacTab);
            app.Image2.ImageSource = 'play.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end