classdef Interfaz_Inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterfazR17Panel                matlab.ui.container.Panel
        InterfazR17Button               matlab.ui.control.Button
        AyudaPanel                      matlab.ui.container.Panel
        ManualdeUsuarioButton           matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        DevFernandoSandovalLabel_2      matlab.ui.control.Label
        BCIUVGLabel                     matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        InterfacesderecoleccindedatosparaentrenamientosPanel  matlab.ui.container.Panel
        RecoleccinDatosEMGEEGButton     matlab.ui.control.Button
        InterfacesdeEntrenamientoPanel  matlab.ui.container.Panel
        EpylepticAnalysisToolboxButton  matlab.ui.control.Button
        EntrenamientoEMGEEGButton       matlab.ui.control.Button
        DevFernandoSandovalLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EpylepticAnalysisToolboxButton
        function EpylepticAnalysisToolboxButtonPushed(app, event)
            loginanotaciones(app)
            delete(app)
            
        end

        % Button pushed function: EntrenamientoEMGEEGButton
        function EntrenamientoEMGEEGButtonPushed(app, event)
            Interfaz_Entrenamiento(app)
            delete(app)
        end

        % Button pushed function: InterfazR17Button
        function InterfazR17ButtonPushed(app, event)
            R17FK_app
            delete(app)
        end

        % Button pushed function: RecoleccinDatosEMGEEGButton
        function RecoleccinDatosEMGEEGButtonPushed(app, event)
            RecoleccionDeDatos(app)
            delete(app)
        end

        % Button pushed function: ManualdeUsuarioButton
        function ManualdeUsuarioButtonPushed(app, event)
            % Button pushed function: ConsultarManualButton

        % Specify the file name of the PDF in the current working directory
        pdfFileName = 'Manual_de_Usuario__BCI_UVG.pdf'; % Replace 'your_pdf_file.pdf' with the actual file name
        
        % Check if the PDF file exists in the current working directory
        if exist(pdfFileName, 'file')
            % Create a full file path to the PDF file
            pdfFilePath = fullfile(pwd, pdfFileName);
            
            % Open the PDF file in the default PDF viewer
            try
                open(pdfFilePath);
            catch
                msgbox('Failed to open the PDF file. Ensure that you have a default PDF viewer installed.', 'Error', 'error');
            end
        else
            % PDF file not found
            msgbox('No se encuentra el pdf', 'File Not Found', 'error');
        end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DevFernandoSandovalLabel = uilabel(app.UIFigure);
            app.DevFernandoSandovalLabel.Text = 'Dev. Fernando Sandoval';
            app.InterfacesdeEntrenamientoPanel = uipanel(app.UIFigure);
            app.InterfacesdeEntrenamientoPanel.Title = 'Interfaces de Entrenamiento';
            app.EntrenamientoEMGEEGButton = uibutton(app.InterfacesdeEntrenamientoPanel, 'push');
            app.EntrenamientoEMGEEGButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenamientoEMGEEGButtonPushed, true);
            app.EntrenamientoEMGEEGButton.Text = 'Entrenamiento EMG/EEG';
            app.EpylepticAnalysisToolboxButton = uibutton(app.InterfacesdeEntrenamientoPanel, 'push');
            app.EpylepticAnalysisToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @EpylepticAnalysisToolboxButtonPushed, true);
            app.EpylepticAnalysisToolboxButton.Text = 'Epyleptic Analysis Toolbox';
            app.InterfacesderecoleccindedatosparaentrenamientosPanel = uipanel(app.UIFigure);
            app.InterfacesderecoleccindedatosparaentrenamientosPanel.Title = 'Interfaces de recolección de datos para entrenamientos ';
            app.RecoleccinDatosEMGEEGButton = uibutton(app.InterfacesderecoleccindedatosparaentrenamientosPanel, 'push');
            app.RecoleccinDatosEMGEEGButton.ButtonPushedFcn = createCallbackFcn(app, @RecoleccinDatosEMGEEGButtonPushed, true);
            app.RecoleccinDatosEMGEEGButton.Text = 'Recolección Datos EMG/EEG';
            app.Panel = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'banner.jpg';
            app.BCIUVGLabel = uilabel(app.Panel);
            app.BCIUVGLabel.Text = 'BCI  - UVG';
            app.DevFernandoSandovalLabel_2 = uilabel(app.Panel);
            app.DevFernandoSandovalLabel_2.Text = 'Dev. Fernando Sandoval';
            app.AyudaPanel = uipanel(app.UIFigure);
            app.AyudaPanel.Title = 'Ayuda';
            app.ManualdeUsuarioButton = uibutton(app.AyudaPanel, 'push');
            app.ManualdeUsuarioButton.ButtonPushedFcn = createCallbackFcn(app, @ManualdeUsuarioButtonPushed, true);
            app.ManualdeUsuarioButton.Text = 'Manual de Usuario';
            app.InterfazR17Panel = uipanel(app.UIFigure);
            app.InterfazR17Panel.Title = 'Interfaz R17';
            app.InterfazR17Button = uibutton(app.InterfazR17Panel, 'push');
            app.InterfazR17Button.ButtonPushedFcn = createCallbackFcn(app, @InterfazR17ButtonPushed, true);
            app.InterfazR17Button.Text = 'Interfaz R17';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end